classdef TrackingAppV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        LoadFilterTab                   matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        LoadTrackingButton              matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        DBandpassFilterPanel            matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        OrderSpinner                    matlab.ui.control.Spinner
        OrderSpinnerLabel               matlab.ui.control.Label
        BandpassFilterButton            matlab.ui.control.StateButton
        BandpassImageContrastButton     matlab.ui.control.Button
        BandpassHighLevelEditField      matlab.ui.control.NumericEditField
        BandpassHighLevelEditFieldLabel  matlab.ui.control.Label
        BandpassLowLevelEditField       matlab.ui.control.NumericEditField
        BandpassLowLevelEditFieldLabel  matlab.ui.control.Label
        PassbandLowEditField            matlab.ui.control.NumericEditField
        PassbandLowEditFieldLabel       matlab.ui.control.Label
        PassbandHighEditField           matlab.ui.control.NumericEditField
        PassbandHighEditFieldLabel      matlab.ui.control.Label
        DMedianFilterPanel              matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        MedianFilterButton              matlab.ui.control.Button
        windowsizeSpinner               matlab.ui.control.Spinner
        windowsizeSpinnerLabel          matlab.ui.control.Label
        UnfilteredWindowLevelPanel      matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        Series2ImageContrastButton      matlab.ui.control.Button
        WindowLevel2HighEditField       matlab.ui.control.NumericEditField
        Series2HighLevelEditFieldLabel  matlab.ui.control.Label
        WindowLevel2LowEditField        matlab.ui.control.NumericEditField
        Series2LowLevelEditFieldLabel   matlab.ui.control.Label
        Series1ImageContrastButton      matlab.ui.control.Button
        WindowLevel1HighEditField       matlab.ui.control.NumericEditField
        Series1HighLevelEditFieldLabel  matlab.ui.control.Label
        WindowLevel1LowEditField        matlab.ui.control.NumericEditField
        Series1LowLevelEditFieldLabel   matlab.ui.control.Label
        LoadSecondImageSeriesButton     matlab.ui.control.StateButton
        LoadFirstImageSeriesButton      matlab.ui.control.StateButton
        TrackingTab                     matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        AdjustTrackingPanel             matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        ManualAdjustTab                 matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        SeriestoadjustDropDown          matlab.ui.control.DropDown
        SeriestoadjustDropDownLabel     matlab.ui.control.Label
        AdjustButton                    matlab.ui.control.Button
        leadtoadjustDropDown            matlab.ui.control.DropDown
        leadtoadjustDropDownLabel       matlab.ui.control.Label
        SmartAdjustTab                  matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        zTHISPANELISNOTYETIMPLEMENTEDLabel  matlab.ui.control.Label
        PreviousFramebkeyButton         matlab.ui.control.Button
        NextFramespacebarButton         matlab.ui.control.Button
        AdjustTrackingSwitch            matlab.ui.control.Switch
        AdjustTrackingSwitchLabel       matlab.ui.control.Label
        TrackPanel                      matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        ObjectCenterSeries2EditField2   matlab.ui.control.NumericEditField
        ObjectCenterSeries1EditField2   matlab.ui.control.NumericEditField
        TrackButton                     matlab.ui.control.Button
        DefineTemplateSeries2Button     matlab.ui.control.Button
        DefineTemplateSeries1Button     matlab.ui.control.Button
        DefineObjectCenterSeries2Button  matlab.ui.control.Button
        DefineObjectCenterSeries1Button  matlab.ui.control.Button
        ObjectCenterSeries2EditField    matlab.ui.control.NumericEditField
        ObjectCenterSeries1EditField    matlab.ui.control.NumericEditField
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        NewTrackingObjectButton         matlab.ui.control.Button
        CurrentObjectDropDown           matlab.ui.control.DropDown
        CurrentObjectDropDownLabel      matlab.ui.control.Label
        TrackingSettingsPanel           matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        ReferenceFrameSpinner           matlab.ui.control.Spinner
        ReferenceFrameSpinnerLabel      matlab.ui.control.Label
        ImagesforAutoTrackingDropDown   matlab.ui.control.DropDown
        ImagesforAutoTrackingDropDownLabel  matlab.ui.control.Label
        MaximumDisplacementFromReferencePositionPixelsEditField  matlab.ui.control.NumericEditField
        MaximumTotalDisplacementmmLabel  matlab.ui.control.Label
        LimitTrackingSearchRangeCheckBox  matlab.ui.control.CheckBox
        AutomatedTrackingDropDown       matlab.ui.control.DropDown
        AutomatedTrackingDropDownLabel  matlab.ui.control.Label
        TriangulateTab                  matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        ImagerGeometryPanel             matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        TriangulateButton               matlab.ui.control.Button
        SourcetoImageDistancemmEditField  matlab.ui.control.NumericEditField
        SourcetoImageDistancemmEditFieldLabel  matlab.ui.control.Label
        PixelSizemmEditField            matlab.ui.control.NumericEditField
        PixelSizemmEditField_2Label     matlab.ui.control.Label
        GantryAngledegEditField         matlab.ui.control.NumericEditField
        GantryAngledegEditFieldLabel    matlab.ui.control.Label
        SeparateMotionsTab              matlab.ui.container.Tab
        GridLayout29                    matlab.ui.container.GridLayout
        SeparateMotionsButton           matlab.ui.control.Button
        DetermineCutOffFrequencyPanel   matlab.ui.container.Panel
        GridLayout31                    matlab.ui.container.GridLayout
        CoordinateforamplitudespectrumDropDown  matlab.ui.control.DropDown
        CoordinateforamplitudespectrumDropDownLabel  matlab.ui.control.Label
        UsescurrentlyviewedimagesLabel  matlab.ui.control.Label
        ImageDropDown                   matlab.ui.control.DropDown
        ImageDropDownLabel              matlab.ui.control.Label
        NewRespiratoryMotionSurrogateButton  matlab.ui.control.Button
        MotionAngleEditField            matlab.ui.control.NumericEditField
        MotionAngleEditFieldLabel       matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        FilteringParametersPanel        matlab.ui.container.Panel
        GridLayout30                    matlab.ui.container.GridLayout
        SamplingFrequencyHzEditField    matlab.ui.control.NumericEditField
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        FcutFrLFcFrLabel                matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        FcutEditField                   matlab.ui.control.NumericEditField
        FcutEditFieldLabel              matlab.ui.control.Label
        RespiratoryPeriodsecbreathEditField  matlab.ui.control.NumericEditField
        RespiratoryPeriodsecbreathEditFieldLabel  matlab.ui.control.Label
        HeartRateEditField              matlab.ui.control.NumericEditField
        HeartRatebpmEditFieldLabel      matlab.ui.control.Label
        SaveTab                         matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        Tree                            matlab.ui.container.CheckBoxTree
        ImageDataSeries1SaveNode        matlab.ui.container.TreeNode
        Tree2                           matlab.ui.container.CheckBoxTree
        ImageDataSeries2SaveNode        matlab.ui.container.TreeNode
        TrackingMovieCheckBox           matlab.ui.control.CheckBox
        FileFormatDate_Identifier_XXXXXLabel  matlab.ui.control.Label
        FileIdentifierEditField         matlab.ui.control.EditField
        FileIdentifierFormatDate_Identifier_XXXXLabel  matlab.ui.control.Label
        SeparatedmotionsplotsCheckBox   matlab.ui.control.CheckBox
        MotionSurrogateCheckBox         matlab.ui.control.CheckBox
        TrackingDataCheckBox            matlab.ui.control.CheckBox
        SaveButton                      matlab.ui.control.StateButton
        CenterPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout28                    matlab.ui.container.GridLayout
        FrameLabel                      matlab.ui.control.Label
        NextFrameButton                 matlab.ui.control.Button
        PreviousFrameButton             matlab.ui.control.Button
        GridLayout24                    matlab.ui.container.GridLayout
        FrameDelaysEditField            matlab.ui.control.NumericEditField
        FrameDelaysEditFieldLabel       matlab.ui.control.Label
        PlayFramesButton                matlab.ui.control.Button
        ShowTrackedLeadsCheckBox        matlab.ui.control.CheckBox
        GridLayout23                    matlab.ui.container.GridLayout
        ImagetoViewDropDownLabel        matlab.ui.control.Label
        ImagetoViewDropDown             matlab.ui.control.DropDown
        ProjectionSlider                matlab.ui.control.Slider
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Jakob Marshall
% 2023
%
%
% Created under the supervision of Dr. Steven Thomas. Based on 
% code originally written by Dr. Thomas. 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    properties (Access = public)
        imagerData           % Structure with image projections
        imagerData2          % Structure with image projections
        imagerDataInfo       % Information about image projections
        imagerDataInfo2      % Information about image projections
        nFrames              % Number of image projections
        trackingData         % Tracking Data
        motionSurrogate      % Surrogate for respiratory motion
        defaultPath          % default path for loading/saving/initially defined by user
    end
    
%     properties (Access = private)
%                 
%         externalFig % External figure for plotting
% 
%     end
        
        
    methods (Access = private)

        function UpdateAxis1(app) %main axis showing image data series 1
        c = 1;%counter for legend   
            f = app.ProjectionSlider.Value;
            
            %Determine image series to plot
            imIndex = app.ImageIndexLookup(app.ImagetoViewDropDown.Value);
                        
            imagesc(app.UIAxes,app.imagerData{imIndex,f},app.imagerDataInfo.WindowLevel(imIndex,:))
            app.UIAxes.Colormap = gray;
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.XLim = [1 size(app.imagerData{1,f},2)];
            app.UIAxes.YLim = [1 size(app.imagerData{1,f},1)];

            %Plot tracked points
                if app.ShowTrackedLeadsCheckBox.Value %if the checkbox is on & isTracked initiated
                    for obj = 1:length(app.trackingData) %for each tracking object
                       if isfield(app.trackingData{obj},'isTracked') %if it is even a field
                            if app.trackingData{obj}.isTracked %if its tracked
                                hold(app.UIAxes,'on');
                                plot(app.UIAxes,app.trackingData{obj}.adjTrackedPts(f,1,1),app.trackingData{obj}.adjTrackedPts(f,2,1),'o','MarkerSize',14,'LineWidth',1.7);
                                hold(app.UIAxes,'off')
                                leg{c} = app.trackingData{obj}.name;
                                c=c+1;
                            end
                       end
                    end
                end
          % if exist('leg','var')    
           %   legend(app.UIAxes,leg,'FontSize',16)
           %end
        end
        
        function UpdateAxis2(app) %main axis showing image data series 2
            if ~isempty(app.imagerData2)          
                f = app.ProjectionSlider.Value;
                
                %Determine image series to plot 
                imIndex = app.ImageIndexLookup(app.ImagetoViewDropDown.Value);
                            
                imagesc(app.UIAxes2,app.imagerData2{imIndex,f},app.imagerDataInfo2.WindowLevel(imIndex,:))
                app.UIAxes2.Colormap = gray;
                app.UIAxes2.DataAspectRatio = [1,1,1];
                app.UIAxes2.XLim = [1 size(app.imagerData2{1,f},2)];
                app.UIAxes2.YLim = [1 size(app.imagerData2{1,f},1)];

            %Plot tracked points
                if app.ShowTrackedLeadsCheckBox.Value %if the checkbox is on & isTracked initiated
                    for obj = 1:length(app.trackingData) %for each tracking object
                       if isfield(app.trackingData{obj},'isTracked')%if it is even a field
                            if app.trackingData{obj}.isTracked %if its tracked
                                hold(app.UIAxes2,'on');
                                plot(app.UIAxes2,app.trackingData{obj}.adjTrackedPts(f,1,2),app.trackingData{obj}.adjTrackedPts(f,2,2),'o','MarkerSize',14,'LineWidth',1.7);
                                hold(app.UIAxes2,'off')
                            end
                       end
                    end
                end
            end
        end

        function UpdateAxis4(app) %amplitude spectrum of motion data
            
            N = app.nFrames;
            freqVec = (0:N-1)*(app.SamplingFrequencyHzEditField.Value/N);
            %app.SamplingFrequencyHzEditField.Value*(0:(N/2))/N;
            plotVec = 1:floor(N/2);
            
            switch app.CoordinateforamplitudespectrumDropDown.Value
                case 'x'
                    k = 1;
                case 'y'
                    k = 2;
                case 'z'
                    k = 3;
                otherwise
                     error('Not properly assigning between x, y, z');
            end


            cla(app.UIAxes4);%clear anything from before

            for obj = 1:length(app.trackingData) %for each tracking object
               if isfield(app.trackingData{obj},'fftAmplitudeData') %if it is there
                    hold(app.UIAxes4,'on');
                    plot(app.UIAxes4,freqVec(plotVec),app.trackingData{obj}.fftAmplitudeData(plotVec,k),'-','LineWidth',1);
                    hold(app.UIAxes4,'off')
               end
            end

            if isfield(app.motionSurrogate,'edgePosition')
                surrogateAmplitudeSpectrum = app.AmplitudeSpectrum(0.5*0.388*app.motionSurrogate.edgePosition);%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                hold(app.UIAxes4,'on');
                plot(app.UIAxes4,freqVec(plotVec),surrogateAmplitudeSpectrum(plotVec),'-','MarkerSize',14,'LineWidth',1.7,'Color',[0 0.4 0]);
                hold(app.UIAxes4,'off')
            end
                yLim = app.UIAxes4.YLim(2);
            %Also plot the values that determine the cut-off frequency
            hold(app.UIAxes4,'on');
            xline(app.UIAxes4,app.HeartRateEditField.Value/60,'--','LineWidth',1.7,'Color',[0.4 0 0]);
                text(app.UIAxes4,app.HeartRateEditField.Value/60,0.65*yLim,'  Cardiac'); 
            xline(app.UIAxes4,1/app.RespiratoryPeriodsecbreathEditField.Value,'--','LineWidth',1.7,'Color',[0 0 0.3]);
                text(app.UIAxes4,1/app.RespiratoryPeriodsecbreathEditField.Value,0.95*yLim,'  Respiratory'); 
            xline(app.UIAxes4,app.FcutEditField.Value,'--','LineWidth',2.2,'Color',[0 0 0]);
                text(app.UIAxes4,app.FcutEditField.Value,0.8*yLim,'Fcut'); 
            hold(app.UIAxes4,'off');
        end
       
        function objectIndex = CurrentObject(app)
            allItems = app.CurrentObjectDropDown.Items;
            currentItem = app.CurrentObjectDropDown.Value;
            %find where value is equal to all items
            objectIndex = find(string(allItems)==currentItem);
        end
        
        function UpdateTrackPanel(app)
%Updates the panel 'Track'            
            readyToTrack = 1;

            %determine current object
            oI = CurrentObject(app);%object index

      %%%Ensure all the required parameters are there. If not set to default
            %Name box
                if isempty(app.trackingData) || ~isfield(app.trackingData{oI},'name') %first boolean prevents coming in with empty struct
                    app.trackingData{oI}.name = '';
                end
             %trackingArea Defined Boolean
                if ~isfield(app.trackingData{oI},'trackingAreaDefined1')
                    app.trackingData{oI}.trackingAreaDefined1 = 0;
                end
                if ~isfield(app.trackingData{oI},'trackingAreaDefined2')
                    app.trackingData{oI}.trackingAreaDefined2 = 0;
                end
             %trackingCenterDefined Boolean   
                if ~isfield(app.trackingData{oI},'trackingCenterDefined1')
                    app.trackingData{oI}.trackingCenterDefined1 = 0;
                end
                if ~isfield(app.trackingData{oI},'trackingCenterDefined2')
                    app.trackingData{oI}.trackingCenterDefined2 = 0;
                end
             %is tracked
                if ~isfield(app.trackingData{oI},'isTracked')
                    app.trackingData{oI}.isTracked = 0;
                end
              %is altered
                if ~isfield(app.trackingData{oI},'isAltered')
                    app.trackingData{oI}.isAltered = 0;
                end
            
      %%%Fill in Name Box          
            app.NameEditField.Value = app.trackingData{oI}.name;
            
            %Fill in boxes based on if defined
            
       %%%Tracking Reference Coordinate Center Defined
            if app.trackingData{oI}.trackingCenterDefined1
                %Put numbers in boxes
                app.ObjectCenterSeries1EditField.Value = app.trackingData{oI}.trackingCenter(1,1,1);
                app.ObjectCenterSeries1EditField2.Value = app.trackingData{oI}.trackingCenter(1,2,1);
                %color of pushbutton - indicates status
                app.DefineObjectCenterSeries1Button.BackgroundColor = [0.8 0.9 0.8];
            else
                app.DefineObjectCenterSeries1Button.BackgroundColor = [0.96,0.96,0.96];
                app.ObjectCenterSeries1EditField.Value = 0;
                app.ObjectCenterSeries1EditField2.Value = 0;
                readyToTrack = 0;
            end
            %SECOND SERIES
            if app.trackingData{oI}.trackingCenterDefined2
                %Put numbers in boxes
                app.ObjectCenterSeries2EditField.Value = app.trackingData{oI}.trackingCenter(1,1,2);
                app.ObjectCenterSeries2EditField2.Value = app.trackingData{oI}.trackingCenter(1,2,2);
                %color of pushbutton - indicates status
                app.DefineObjectCenterSeries2Button.BackgroundColor = [0.8 0.9 0.8];
            else
                app.DefineObjectCenterSeries2Button.BackgroundColor = [0.96,0.96,0.96];
                app.ObjectCenterSeries2EditField.Value = 0;
                app.ObjectCenterSeries2EditField2.Value = 0;
                readyToTrack = 0;
            end

       %%%Tracking Area Defined
            if app.trackingData{oI}.trackingAreaDefined1
                app.DefineTemplateSeries1Button.BackgroundColor = [0.8 0.9 0.8];
            else
                app.DefineTemplateSeries1Button.BackgroundColor = [0.96,0.96,0.96];
                readyToTrack = 0;
            end


            if app.trackingData{oI}.trackingAreaDefined2
                app.DefineTemplateSeries2Button.BackgroundColor = [0.8 0.9 0.8];
            else
                app.DefineTemplateSeries2Button.BackgroundColor = [0.96,0.96,0.96];
                readyToTrack = 0;
            end

       %%%Status of if it is tracked
            if app.trackingData{oI}.isTracked
                if app.trackingData{oI}.isAltered%if something has been altered
                    %Set to red... altered
                    app.TrackButton.BackgroundColor = [0.7 0.4 0.4];
                else
                    %Set to green
                    app.TrackButton.BackgroundColor = [0.8 0.9 0.8];
                end

            elseif readyToTrack
                %Set to purple
                app.TrackButton.BackgroundColor = [0.6 0.4 0.6];
            else
                %default
                app.TrackButton.BackgroundColor = [0.96,0.96,0.96];

            end
          %save ready to track boolean
          app.trackingData{oI}.isReadyToTrack = readyToTrack;

          %Match Adjust tracking tab to have all the same objects as the
          %tracking tab.
          app.leadtoadjustDropDown.Items = app.CurrentObjectDropDown.Items;
        end
        
        function imIndex = ImageIndexLookup(app,imTypeString)
            %Determine image series to plot
            switch imTypeString
                case 'Unfiltered'
                    imIndex = 1;%app.imagerDataInfo.rawIndex; 
                case 'Median'
                    imIndex = app.imagerDataInfo.medianIndex;
                case 'Bandpass'
                    imIndex = app.imagerDataInfo.bandpassIndex;
                otherwise
                    warning('Unexpected image index')
            end    
        end

        function getTrackParameters(app)
            oI = CurrentObject(app);

            %Update structure:
            app.trackingData{oI}.name = app.NameEditField.Value;
            
            %Reference frame
            app.trackingData{oI}.referenceFrame = app.imagerDataInfo.referenceFrame;

            %Take values from edit fields for reference coordinate center
            app.trackingData{oI}.trackingCenter(1,1,1) = app.ObjectCenterSeries1EditField.Value;
            app.trackingData{oI}.trackingCenter(1,2,1) = app.ObjectCenterSeries1EditField2.Value;
            app.trackingData{oI}.trackingCenter(1,1,2) = app.ObjectCenterSeries2EditField.Value;
            app.trackingData{oI}.trackingCenter(1,2,2) = app.ObjectCenterSeries2EditField2.Value;
  
            if ~isfield(app.trackingData{oI},'isTracked')%this means first object- otherwise would be initiated.
                %Booleans of progress
                app.trackingData{oI}.isTracked = 0;%boolean of if has been tracked.
                app.trackingData{oI}.isAltered = 0;%boolean of if has been alterred since tracking.
                app.trackingData{oI}.trackingCenterDefined1 = 0;%boolean of if tracking centre has been defined
                app.trackingData{oI}.trackingCenterDefined2 = 0;%boolean of if tracking centre has been defined
                app.trackingData{oI}.trackingAreaDefined1 = 0;%boolean of if tracking area has been defined
                app.trackingData{oI}.trackingAreaDefined2 = 0;%boolean of if tracking area has been defined
            end
        end
        
        function trackedPts = autoTrackNormXCorr2(app)
            
            oI = CurrentObject(app);%current object index

            %Set-up template that will be searched for in all other images
            iT = app.ImageIndexLookup(app.ImagesforAutoTrackingDropDown.Value);%indice for tracking
            iRF = app.imagerDataInfo.referenceFrame;%reference frame indice
            
            %Configure search templates for series 1 and 2.
             [app.trackingData{oI}.searchTemplateROI,app.trackingData{oI}.searchTemplateROIOffset] = ...
                 app.ConfigureSearchTemplate(app.imagerData{iT,iRF},app.trackingData{oI}.trackingArea1,app.trackingData{oI}.trackingCenter(:,:,1));
    
             [app.trackingData{oI}.searchTemplateROI2,app.trackingData{oI}.searchTemplateROIOffset2] = ...
                 app.ConfigureSearchTemplate(app.imagerData2{iT,iRF},app.trackingData{oI}.trackingArea2,app.trackingData{oI}.trackingCenter(:,:,2));

            %preallocate
             trackedPts = zeros(app.nFrames,2,2);%third index: series 1 and 2;
            
                    if app.LimitTrackingSearchRangeCheckBox.Value %limit where to search for lead within.
        
                        maxDisplacement = app.MaximumDisplacementFromReferencePositionPixelsEditField.Value; %in pixels
                        
                        xVals = round(app.trackingData{oI}.trackingCenter(:,1,1) - maxDisplacement):round(app.trackingData{oI}.trackingCenter(:,1,1) + maxDisplacement);
                        yVals = round(app.trackingData{oI}.trackingCenter(:,2,1) - maxDisplacement):round(app.trackingData{oI}.trackingCenter(:,2,1) + maxDisplacement);
        
                        xVals2 = round(app.trackingData{oI}.trackingCenter(:,1,2) - maxDisplacement):round(app.trackingData{oI}.trackingCenter(:,1,2) + maxDisplacement);
                        yVals2 = round(app.trackingData{oI}.trackingCenter(:,2,2) - maxDisplacement):round(app.trackingData{oI}.trackingCenter(:,2,2) + maxDisplacement);
                            
                        %adjust extracted values to account for any errors
                            %Must be extracting elements with indices>1
                            xVals = xVals(xVals>=1); yVals = yVals(yVals>=1);%must be within image (low >= 1)
                            xVals2 = xVals2(xVals2>=1); yVals2 = yVals2(yVals2>=1);%must be within image (low >= 1)
                            %Must have indices less than size of image
                            sIm = size(app.imagerData{1,1});sIm2 = size(app.imagerData2{1,1});
                            xVals = xVals(xVals<=sIm(2)); yVals = yVals(yVals<=sIm(1));%must be within image (low >= 1)
                            xVals2 = xVals2(xVals2<=sIm2(2)); yVals2 = yVals2(yVals2<=sIm2(1));%must be within image (low >= 1)
        
                        %Extract portion of images... will speed up normxcorr2
                        imset1 = cell(1,app.nFrames);imset2 = cell(1,app.nFrames);%preallocate
                        for f = 1:app.nFrames
                            imset1{f} = app.imagerData{iT,f}(yVals,xVals);
                            imset2{f} = app.imagerData2{iT,f}(yVals2,xVals2);
                        end
        
                    else %dont limit tracking... use full images
                        imset1 = app.imagerData(iT,:);
                        imset2 = app.imagerData2(iT,:);
                    end
            
             wb = waitbar(0,'Tracking','name','Be Patient');%waitbar
                
             for f = 1:app.nFrames%for each frame
                corrCoeff = normxcorr2(app.trackingData{oI}.searchTemplateROI,imset1{f});
                [trackedPts(f,2,1),trackedPts(f,1,1)] = find(corrCoeff == max(corrCoeff(:)));
                
                corrCoeff2 = normxcorr2(app.trackingData{oI}.searchTemplateROI2,imset2{f});
                [trackedPts(f,2,2),trackedPts(f,1,2)] = find(corrCoeff2 == max(corrCoeff2(:)));
                
                if rem(f,5) == 0 %update waitbar
                waitbar(f/app.nFrames,wb); 
                end
            end
            close(wb);
    
           %account for offest from template ROI center and object center
            trackedPts(:,:,1) = trackedPts(:,:,1) + app.trackingData{oI}.searchTemplateROIOffset;
            trackedPts(:,:,2) = trackedPts(:,:,2) + app.trackingData{oI}.searchTemplateROIOffset2;

                if app.LimitTrackingSearchRangeCheckBox.Value %limit where to search for lead within.
                  %account for shifting of image
                    trackedPts(:,1,1) = trackedPts(:,1,1) + xVals(1)-1;
                    trackedPts(:,2,1) = trackedPts(:,2,1) + yVals(1)-1;
                    %series2
                    trackedPts(:,1,2) = trackedPts(:,1,2) + xVals2(1)-1;
                    trackedPts(:,2,2) = trackedPts(:,2,2) + yVals2(1)-1;
                end
        end


        function AnalyzeMotions(app)
            
            %Determine what image filtering type
            imI = ImageIndexLookup(app,app.ImagetoViewDropDown.Value);

            %Determine what series of image
              if any(contains(app.ImageDropDown.Value,'Series 1','IgnoreCase',true))
                allI = app.imagerData(imI,:);
              elseif any(contains(app.ImageDropDown.Value,'Series 2','IgnoreCase',true))
                allI = app.imagerData2(imI,:);
              else
                  error('incorrectly choosing between series 1 and 2')
              end

            %Create mask from user drawn rectangle
            app.motionSurrogate.mask = createMask(app.motionSurrogate.roi);
            %Rotate mask
            app.motionSurrogate.rotatedMask = imrotate(app.motionSurrogate.mask,-app.motionSurrogate.roi.RotationAngle);

            %Range of values within mask
            xRange = sum(app.motionSurrogate.rotatedMask,1)>0;
            yRange = sum(app.motionSurrogate.rotatedMask,2)>0;

            edgePosition = zeros(app.nFrames,1);%preallocate
            for f = 1:app.nFrames                                           %For each frame
                I = allI{f};                                                %take image
                rotI = imrotate(I,-app.motionSurrogate.roi.RotationAngle);  %rotate it
                rotImFilt = imboxfilt(rotI(yRange,xRange),9);%imgaussfilt(rotI(yRange,xRange),5);
                averageProfile = mean(rotImFilt,1);  %Average over all rows
                averageProfile2 = (averageProfile-min(averageProfile))/max(averageProfile-min(averageProfile));
                profileGradient = abs(gradient(averageProfile2));            %find the gradient of the average profile
                [~,edgePosition(f)] = max(profileGradient);                 %Max gradient is taken as the edge position
            end
            app.motionSurrogate.edgePosition = edgePosition;
            %Update plot
            timeVec = (0:(app.nFrames-1))*(1/app.SamplingFrequencyHzEditField.Value);
            plot(app.UIAxes3,timeVec,edgePosition,'Color',[0 0.4 0]);
             %Fill in motion angle in app
             app.MotionAngleEditField.Value = app.motionSurrogate.roi.RotationAngle;
           
             %Update the plot of the amplitude spectrum
           UpdateAxis4(app)
        end

        function MotionSurrogateRoiMoved(app,~,evt)
            %Update motion surrogate
            if strcmp(evt.EventName,'ROIMoved')
               AnalyzeMotions(app)
            elseif strcmp(evt.EventName,'DeletingROI')
                %remove object to remove listener
                app.motionSurrogate.roiCopy = app.motionSurrogate.roi;
                delete(app.motionSurrogate.roi)
            end
        end


        function VisualizeSeparatedMotions(app,varargin)
            %%%%%%%%%
            %Varargin: opt - for saving files   
            %   opt.save            %boolean of if to save
            %   opt.saveFolder      %folder to save to
            %   opt.date            %date included in file names
            %   opt.identifier      %identifier included in file names

            if ~isempty(varargin) %if opt included
                opt = varargin{1};
                if opt.save %if saving
                    %create new folder just for plots
                    saveDir = [opt.saveFolder,'\Figures'];
                    if ~isfolder(saveDir)
                        mkdir(saveDir)
                    end
                end
            end

            sepObj = [];leg = {};
            %Plot of total motion, cardiac motion, and respiratory motion.
            %Each object gets its own separate plot.
            for I = 1:length(app.trackingData)
                if isfield(app.trackingData{I},'respiratoryMotion')%only present if motion separated
                    t = app.trackingData{I}.t;%time Vector
                
                    f=figure();f.Position = [328,152,1213,955];
                    subplot(3,1,1)
                    plot(t,app.trackingData{I}.totalMotionZeroMean(:,1),'.-','color',[0 0.5 0],'MarkerSize',15,'LineWidth',1.8)
                    hold on
                    plot(t,app.trackingData{I}.respiratoryMotionZeroMean(:,1),'b.-','MarkerSize',15,'LineWidth',1.8)
                    plot(t,app.trackingData{I}.cardiacMotionZeroMean(:,1),'r.-','MarkerSize',15,'LineWidth',1.8)
                    hold off
                    ylabel('X Motion (mm)','FontSize',15)
                    xlabel('time (s)','FontSize',15)
                    legend({'Total','Respiratory','Cardiac'},'FontSize',15)
                    legend('boxoff');legend('location','NorthOutside');legend('Orientation','horizontal');
                    set(gca,'LineWidth',1.5,'FontSize',14)
                    

                    subplot(3,1,2)
                    plot(t,app.trackingData{I}.totalMotionZeroMean(:,2),'.-','color',[0 0.5 0],'MarkerSize',15,'LineWidth',1.8)
                    hold on
                    plot(t,app.trackingData{I}.respiratoryMotionZeroMean(:,2),'b.-','MarkerSize',15,'LineWidth',1.8)
                    plot(t,app.trackingData{I}.cardiacMotionZeroMean(:,2),'r.-','MarkerSize',15,'LineWidth',1.8)
                    hold off
                    ylabel('Y Motion (mm)','FontSize',15)
                    xlabel('time (s)','FontSize',15)
                    set(gca,'LineWidth',1.5,'FontSize',14)

                    subplot(3,1,3)
                    plot(t,app.trackingData{I}.totalMotionZeroMean(:,3),'.-','color',[0 0.5 0],'MarkerSize',15,'LineWidth',1.8)
                    hold on
                    plot(t,app.trackingData{I}.respiratoryMotionZeroMean(:,3),'b.-','MarkerSize',15,'LineWidth',1.8)
                    plot(t,app.trackingData{I}.cardiacMotionZeroMean(:,3),'r.-','MarkerSize',15,'LineWidth',1.8)
                    hold off
                    ylabel('Z Motion (mm)','FontSize',15)
                    xlabel('time (s)','FontSize',15)
                    set(gca,'LineWidth',1.5,'FontSize',14)

                    sgtitle(['Motion Plots Object ',num2str(I),': ',app.trackingData{I}.name],'FontSize',20)
                    
                    if ~isempty(varargin) && opt.save%save plot
                       saveas(f,[saveDir,'\',opt.date,'_',opt.identifier,'_MotionPlots_Object ',num2str(I),app.trackingData{I}.name],'fig')
                       saveas(f,[saveDir,'\',opt.date,'_',opt.identifier,'_MotionPlots_Object ',num2str(I),app.trackingData{I}.name],'png')
                       close(f)
                    end

                sepObj = [sepObj,I];
                leg{end+1} = ['Object ',num2str(I),': ',app.trackingData{I}.name];
                end
            end
            nColumns = 2;
            t = app.trackingData{sepObj(1)}.t;%same for all objects.
        
            %Plots of total motion for all objects.
                f=figure();f.Position = [328,152,1213,955];
                subplot(3,1,1)
                hold on
                for k = 1:length(sepObj)
                plot(t,app.trackingData{sepObj(k)}.totalMotionZeroMean(:,1),'.-','MarkerSize',15,'LineWidth',1.8)
                end
                hold off
                ylabel('X Motion (mm)','FontSize',15)
                xlabel('time (s)','FontSize',15)
                legend(leg,'FontSize',15,'NumColumns',nColumns)
                legend('boxoff');legend('location','NorthOutside');legend('Orientation','horizontal');
                set(gca,'LineWidth',1.5,'FontSize',14)
                
                subplot(3,1,2)
                hold on
                for k = 1:length(sepObj)
                plot(t,app.trackingData{sepObj(k)}.totalMotionZeroMean(:,2),'.-','MarkerSize',15,'LineWidth',1.8)
                end
                hold off
                ylabel('Y Motion (mm)','FontSize',15)
                xlabel('time (s)','FontSize',15)
                set(gca,'LineWidth',1.5,'FontSize',14)
                
                subplot(3,1,3)
                hold on
                for k = 1:length(sepObj)
                plot(t,app.trackingData{sepObj(k)}.totalMotionZeroMean(:,3),'.-','MarkerSize',15,'LineWidth',1.8)
                end
                hold off
                ylabel('Z Motion (mm)','FontSize',15)
                xlabel('time (s)','FontSize',15)
                set(gca,'LineWidth',1.5,'FontSize',14)
                
                sgtitle('Total Motion Plots','FontSize',20)
                    if ~isempty(varargin) && opt.save%save plot
                       saveas(f,[saveDir,'\',opt.date,'_',opt.identifier,'_TotalMotionPlotsAll'],'fig')
                       saveas(f,[saveDir,'\',opt.date,'_',opt.identifier,'_TotalMotionPlotsAll'],'png')
                       close(f)
                    end


            %Plots of cardiac motion for all objects.
                f=figure();f.Position = [328,152,1213,955];
                subplot(3,1,1)
                hold on
                for k = 1:length(sepObj)
                plot(t,app.trackingData{sepObj(k)}.cardiacMotionZeroMean(:,1),'.-','MarkerSize',15,'LineWidth',1.8)
                end
                hold off
                ylabel('X Motion (mm)','FontSize',15)
                xlabel('time (s)','FontSize',15)
                legend(leg,'FontSize',15,'NumColumns',nColumns)
                legend('boxoff');legend('location','NorthOutside');legend('Orientation','horizontal');
                set(gca,'LineWidth',1.5,'FontSize',14)
                
                subplot(3,1,2)
                hold on
                for k = 1:length(sepObj)
                plot(t,app.trackingData{sepObj(k)}.cardiacMotionZeroMean(:,2),'.-','MarkerSize',15,'LineWidth',1.8)
                end
                hold off
                ylabel('Y Motion (mm)','FontSize',15)
                xlabel('time (s)','FontSize',15)
                set(gca,'LineWidth',1.5,'FontSize',14)
                
                subplot(3,1,3)
                hold on
                for k = 1:length(sepObj)
                plot(t,app.trackingData{sepObj(k)}.cardiacMotionZeroMean(:,3),'.-','MarkerSize',15,'LineWidth',1.8)
                end
                hold off
                ylabel('Z Motion (mm)','FontSize',15)
                xlabel('time (s)','FontSize',15)
                set(gca,'LineWidth',1.5,'FontSize',14)
                
                sgtitle('Cardiac Motion Plots','FontSize',20)
                    if ~isempty(varargin) && opt.save%save plot
                       saveas(f,[saveDir,'\',opt.date,'_',opt.identifier,'_CardiacMotionPlotsAll'],'fig')
                       saveas(f,[saveDir,'\',opt.date,'_',opt.identifier,'_CardiacMotionPlotsAll'],'png')
                       close(f)
                    end

            %Plots of respiratory motion for all objects.
                f=figure();f.Position = [328,152,1213,955];
                subplot(3,1,1)
                hold on
                for k = 1:length(sepObj)
                plot(t,app.trackingData{sepObj(k)}.respiratoryMotionZeroMean(:,1),'.-','MarkerSize',15,'LineWidth',1.8)
                end
                hold off
                ylabel('X Motion (mm)','FontSize',15)
                xlabel('time (s)','FontSize',15)
                legend(leg,'FontSize',15,'NumColumns',nColumns)
                legend('boxoff');legend('location','NorthOutside');legend('Orientation','horizontal');
                set(gca,'LineWidth',1.5,'FontSize',14)
                
                subplot(3,1,2)
                hold on
                for k = 1:length(sepObj)
                plot(t,app.trackingData{sepObj(k)}.respiratoryMotionZeroMean(:,2),'.-','MarkerSize',15,'LineWidth',1.8)
                end
                hold off
                ylabel('Y Motion (mm)','FontSize',15)
                xlabel('time (s)','FontSize',15)
                set(gca,'LineWidth',1.5,'FontSize',14)
                
                subplot(3,1,3)
                hold on
                for k = 1:length(sepObj)
                plot(t,app.trackingData{sepObj(k)}.respiratoryMotionZeroMean(:,3),'.-','MarkerSize',15,'LineWidth',1.8)
                end
                hold off
                ylabel('Z Motion (mm)','FontSize',15)
                xlabel('time (s)','FontSize',15)
                set(gca,'LineWidth',1.5,'FontSize',14)
                
                sgtitle('Respiratory Motion Plots','FontSize',20)
                    if ~isempty(varargin) && opt.save%save plot
                       saveas(f,[saveDir,'\',opt.date,'_',opt.identifier,'_RespiratoryMotionPlotsAll'],'fig')
                       saveas(f,[saveDir,'\',opt.date,'_',opt.identifier,'_RespiratoryMotionPlotsAll'],'png')
                       close(f)
                    end
        end
    end %of methods access private
    
        methods ( Static ) 

            function [imagerData,imagerDataInfo,nFrames,pathName] = OpenFilesDefineGeometry(defaultPath)
                    homePath = cd;
                    
                    [imager,pathName] = uigetfile('*.raw','Select Imager Files',defaultPath,'MultiSelect','on');
                    
                    cd(pathName)

                    if ischar(imager)
                        warning('Only 1 frame???')
                        temp = imager;
                        clear imager;
                        imager{1} = temp;
                    end

                    nFrames = size(imager,2);%number of frames

                    imagerData = cell(1,nFrames);%Preallocate
                    imagerDataInfo.pathNames = imager;%Store File Names
                    
                    %Load in images
                        for f=1:nFrames %for each frame
                            
                        fileName = imager{1,f};%get file name
                        fin = fopen(fileName,'r');%open file
                        image=fread(fin,1024*768,'int16');%read in image
                        shapedImage = reshape(image,1024,768);%properly shape image
                        fclose all;
                        imagerData{1,f}=shapedImage;%Store in cell object.
                        end
                    cd(homePath)
            end


            function bandpassFilter =  CreateBandpassFilter(lowPassFreq,highPassFreq,order,imageSize)
                    
                %create filter:   Assumes fft uses m = 2ny+1; n = 2nx+1;
                    ny = imageSize(1);
                    nx = imageSize(2);
                
                    [xMesh,yMesh] = meshgrid(-nx:nx,-ny:ny);%twice as big for zero padding (technically 2n+1 elements including (0,0);
                    
                    distMatrix = sqrt(xMesh.^2 + yMesh.^2);
                    
                    filterLow = 1./(1 + (distMatrix/lowPassFreq).^(2*order));
                    filterHigh = 1./(1 + (distMatrix/highPassFreq).^(2*order));
                    
                    %combine filters to create bandpass
                    bandpassFilter = filterHigh.*(1-filterLow);
            end

            function [searchTemplateROI,searchTemplateROIOffset] = ConfigureSearchTemplate(I,trackingRegionsContour,trackingCenter)
%Inputs:   
    %I: Reference Image for tracking
    %trackingRegionsContour: Contour of Object to track. size is (nPts,X:Y)
    %trackingCenter: center point of object to track. 
                 minVals = round(min(trackingRegionsContour,[],1));
                 maxVals = round(max(trackingRegionsContour,[],1));
                    
                 spanX = minVals(1):maxVals(1);
                 spanY = minVals(2):maxVals(2);

                 searchTemplateROI = I(spanY,spanX);

                 C = normxcorr2(searchTemplateROI,I);
                
                 [j,i]=find(C==max(max(C)));
                
                 searchTemplateROIOffset(1)=trackingCenter(1)-i;
                 searchTemplateROIOffset(2)=trackingCenter(2)-j;
            end


            function [distance, varargout] = DistBetween2Segment(p1, p2, p3, p4)
                % Computes the minimum distance between two line segments. Code
                % is adapted for Matlab from Dan Sunday's Geometry Algorithms originally
                % written in C++
                % http://softsurfer.com/Archive/algorithm_0106/algorithm_0106.htm#dist3D_Segment_to_Segment
                % Usage: Input the start and end x,y,z coordinates for two line segments. 
                % p1, p2 are [x,y,z] coordinates of first line segment and p3,p4 are for
                % second line segment. 
                % Output: scalar minimum distance between the two segments.
                %  Example:
                %	P1 = [0 0 0];     P2 = [1 0 0];
                %   P3 = [0 1 0];     P4 = [1 1 0];
                %	dist = DistBetween2Segment(P1, P2, P3, P4)
                %   dist = 1
                    
                    u = p1 - p2;
                    v = p3 - p4;
                    w = p2 - p4;
                    
                    a = dot(u,u);
                    b = dot(u,v);
                    c = dot(v,v);
                    d = dot(u,w);
                    e = dot(v,w);
                    D = a*c - b*b;
                    sD = D;
                    tD = D;
                    
                    SMALL_NUM = 0.00000001;
                    
                    % compute the line parameters of the two closest points
                    if (D < SMALL_NUM)  % the lines are almost parallel
                        sN = 0.0;       % force using point P0 on segment S1
                        sD = 1.0;       % to prevent possible division by 0.0 later
                        tN = e;
                        tD = c;
                    else                % get the closest points on the infinite lines
                        sN = (b*e - c*d);
                        tN = (a*e - b*d);
                        if (sN < 0.0)   % sc < 0 => the s=0 edge is visible       
                            sN = 0.0;
                            tN = e;
                            tD = c;
                        elseif (sN > sD)% sc > 1 => the s=1 edge is visible
                            sN = sD;
                            tN = e + b;
                            tD = c;
                        end
                    end
                    
                    if (tN < 0.0)            % tc < 0 => the t=0 edge is visible
                        tN = 0.0;
                        % recompute sc for this edge
                        if (-d < 0.0)
                            sN = 0.0;
                        elseif (-d > a)
                            sN = sD;
                        else
                            sN = -d;
                            sD = a;
                        end
                    elseif (tN > tD)       % tc > 1 => the t=1 edge is visible
                        tN = tD;
                        % recompute sc for this edge
                        if ((-d + b) < 0.0)
                            sN = 0;
                        elseif ((-d + b) > a)
                            sN = sD;
                        else 
                            sN = (-d + b);
                            sD = a;
                        end
                    end
                    
                    % finally do the division to get sc and tc
                    if(abs(sN) < SMALL_NUM)
                        sc = 0.0;
                    else
                        sc = sN / sD;
                    end
                    
                    if(abs(tN) < SMALL_NUM)
                        tc = 0.0;
                    else
                        tc = tN / tD;
                    end
                    
                    % get the difference of the two closest points
                    dP = w + (sc * u) - (tc * v);  % = S1(sc) - S2(tc)
                    distance = norm(dP);
                    outV = dP;
                    
                    varargout(1) = {outV};      % vector connecting the closest points
                    varargout(2) = {p2+sc*u};   % Closest point on object 1 
                    varargout(3) = {p4+tc*v};   % Closest point on object 2
            end


            function [amplitudes] = AmplitudeSpectrum(data)
                    
                    N = length(data);
            %Normalize data
            d = (data - mean(data));%/max(data-mean(data));
                    %one sided transform
                    %frequency = sampling_frequency*(0:(N/2))/N;%do this in a different spot
                    Y = fft(d);
                    amplitudes = abs(Y/N);
%                     P2(:) = abs(Y);
%                     amplitudes(:) = P2(1:(N-1)/2);%%%%%%%%%%%%%%%%%%%JM EDITED FOR TEST PURPOSES%+1);
%                     amplitudes(2:end-1) = 2*amplitudes(2:end-1);
            end


        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.defaultPath = cd;
        end

        % Value changed function: LoadFirstImageSeriesButton
        function LoadFirstImageSeriesButtonPushed(app, event)
            % Load first view
            % if isfield(app,'defaultPath')
            %     defaultPath = app.defaultPath;
            % else
            %     defaultPath = cd;
            % end

            [app.imagerData,app.imagerDataInfo,app.nFrames,app.defaultPath] = app.OpenFilesDefineGeometry(app.defaultPath);
            
                    %Update Projection Slider Viewer Limits
                    if app.nFrames == 1
                     app.ProjectionSlider.Limits = [0 1];
                     app.ProjectionSlider.MajorTicks = 0:1;
                     app.ProjectionSlider.MinorTicks = [];
                    else
                     app.ProjectionSlider.Limits = [1 app.nFrames];
                     app.ProjectionSlider.MajorTicks = 1:app.nFrames;
                     app.ProjectionSlider.MinorTicks = [];
                    end
                    
                    %Set default window/level
                    a = min(app.imagerData{1,1},[],'all');
                    b = max(app.imagerData{1,1},[],'all');
                    
                    app.WindowLevel1LowEditField.Value = a;
                    app.WindowLevel1HighEditField.Value = b;
                    app.imagerDataInfo.WindowLevel = [a b];
                    
                    %Update image to view
                    app.ImagetoViewDropDown.Items = {'Unfiltered'};
                    app.ImagetoViewDropDown.Value = {'Unfiltered'};
                    
                    %Define index which chooses component n from
                    %imagerData{n,:}
                    app.imagerDataInfo.rawIndex = 1;

            UpdateAxis1(app);      
        end

        % Value changed function: LoadSecondImageSeriesButton
        function LoadSecondImageSeriesButtonPushed(app, event)
            % Load second view - many things already defined from the first image series.... 
            [app.imagerData2,app.imagerDataInfo2,nFrames2,app.defaultPath] = app.OpenFilesDefineGeometry(app.defaultPath);
            
                %Need the same number of frames between the image series
                if ~(app.nFrames == nFrames2)
                    error('Same number of frames must be loaded for the First and Second Image Series.')
                end
            
                    %Set default window/level
                    a = min(app.imagerData2{1,1},[],'all');
                    b = max(app.imagerData2{1,1},[],'all');
                    
                    app.WindowLevel2LowEditField.Value = a;
                    app.WindowLevel2HighEditField.Value = b;
                    app.imagerDataInfo2.WindowLevel = [a b];

            UpdateAxis2(app); 
        end

        % Button pushed function: LoadTrackingButton
        function LoadTrackingButtonPushed(app, event)
            %Load in data previously tracked on motion tracking app. 

            %Prompt user to select file
             [fName,pName] = uigetfile('*.mat','Select Imager Files','MultiSelect','off');
                   
            if isequal(fName,0)
                disp('User selected Cancel');
            else     
                loadedVal = load(fullfile(pName,fName),'trackingData');
                app.trackingData = loadedVal.trackingData;
                disp(['User selected ', fullfile(pName,fName)]);

                %Update items in tracking tab
                for k = 1:length(app.trackingData)
                   app.CurrentObjectDropDown.Items{k} = ['Object ',num2str(k)];
                end
            end

        end

        % Value changed function: WindowLevel1HighEditField, 
        % ...and 3 other components
        function WindowLevelRawValueChanged(app, event)
%Deals with changes to Window/Level settings on unfiltered images. 
            minLevel1 = app.WindowLevel1LowEditField.Value;
            maxLevel1 = app.WindowLevel1HighEditField.Value;
            
            minLevel2 = app.WindowLevel2LowEditField.Value;
            maxLevel2 = app.WindowLevel2HighEditField.Value;
            
            app.imagerDataInfo.WindowLevel(1,:) = [minLevel1,maxLevel1];
            
            if isfield(app.imagerDataInfo,'medianIndex')
                medFilterIndex = app.imagerDataInfo.medianIndex;%Where data is stored for these images
                app.imagerDataInfo.WindowLevel(medFilterIndex,:) = [minLevel1,maxLevel1];                
            end
            
            %Update axis
            UpdateAxis1(app);
            
            %Do same if image series 2 is loaded in 
            if ~isempty(app.imagerData2)%if loaded in do for image series 2 also...
                
                app.imagerDataInfo2.WindowLevel(1,:) = [minLevel2,maxLevel2];
                
                if isfield(app.imagerDataInfo2,'medianIndex')
                    medFilterIndex = app.imagerDataInfo2.medianIndex;%Where data is stored for these images
                    app.imagerDataInfo2.WindowLevel(medFilterIndex,:) = [minLevel2,maxLevel2];                
                end
            end
            
            %Update axis 
            UpdateAxis2(app);
        end

        % Value changed function: BandpassHighLevelEditField, 
        % ...and 1 other component
        function WindowLevelBandpassChanged(app, event)
% Change to window level of bandpass filtered images
            minLevel = app.BandpassLowLevelEditField.Value;
            maxLevel = app.BandpassHighLevelEditField.Value;

            if isfield(app.imagerDataInfo,'bandpassIndex')    
                bI = app.imagerDataInfo.bandpassIndex;
                app.imagerDataInfo.WindowLevel(bI,:) = [minLevel,maxLevel];
                UpdateAxis1(app);

                if isfield(app.imagerDataInfo2,'bandpassIndex')
                    bI2 = app.imagerDataInfo2.bandpassIndex;%should be equal to bI
                    app.imagerDataInfo2.WindowLevel(bI2,:) = [minLevel,maxLevel];     
                    UpdateAxis2(app);
                end
            else
                warning('Filter images using the bandpass filter before defining window level')
            end

        end

        % Value changed function: ImagetoViewDropDown, ProjectionSlider, 
        % ...and 1 other component
        function ProjectionSliderValueChanged(app, event)
%Changes figures when projection slider is moved. Also callback when
%ImagetoViewDropDown & Show Tracked Leads is changed since it will update the axis.
            
            %round value to nearest integer
            app.ProjectionSlider.Value = round(app.ProjectionSlider.Value);
            
            %update axis
            UpdateAxis1(app);
            UpdateAxis2(app);
            
        end

        % Value changing function: ProjectionSlider
        function ProjectionSliderValueChanging(app, event)
%Changes figures when projection slider is moved.
            
            changingValue = event.Value;
            
            app.ProjectionSlider.Value = round(changingValue);
            UpdateAxis1(app);
            UpdateAxis2(app);
            
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            %Filter data with local median filter
            span = app.windowsizeSpinner.Value;
            
            if ~isfield(app.imagerDataInfo,'medianIndex')%if median filtered images not yet stored... create new index to store them
                medIndex = size(app.imagerData,1)+1;
                
                app.imagerDataInfo.medianIndex = medIndex;%save value in info structure
                app.imagerDataInfo2.medianIndex = medIndex;
            else
                medIndex = app.imagerDataInfo.medianIndex;
            end

            %for each frame
            for f = 1:size(app.imagerData,2)
                app.imagerData{medIndex,f} = medfilt2(app.imagerData{1,f},[span span]);%filter
            end
            
                            
            %use same window and Level for median and raw... will be no significant changes between the two    
            app.imagerDataInfo.WindowLevel(medIndex,:) = app.imagerDataInfo.WindowLevel(1,:);
            
            
            if ~isempty(app.imagerData2)%if loaded in do for image series 2 also...
                for f = 1:size(app.imagerData2,2)
                    app.imagerData2{medIndex,f} = medfilt2(app.imagerData2{1,f},[span span]);
                end
                %update window level
                app.imagerDataInfo2.WindowLevel(medIndex,:) = app.imagerDataInfo2.WindowLevel(1,:);
            end
            
            %add option to view median filtered images in dropdown
                if ~ismember('Median',app.ImagetoViewDropDown.Items)
                    nItems = numel(app.ImagetoViewDropDown.Items);
                    app.ImagetoViewDropDown.Items{nItems+1} = 'Median';
                end

                
            %change plots to view median filter    
            app.ImagetoViewDropDown.Value = 'Median';
            UpdateAxis1(app);
            UpdateAxis2(app);
        end

        % Value changed function: BandpassFilterButton
        function BandPassFilterButtonPushed(app, event)
%Bandpass filters the images
%Currently filters raw images

            % Create bandpass filter
                %Extract properties of filter and create
                lowPassFreq = app.PassbandLowEditField.Value;
                highPassFreq = app.PassbandHighEditField.Value;
                order = app.OrderSpinner.Value;
                imageSize = size(app.imagerData{1,1});  %all images should be the same size... choose an arbitrary one

            bandpassFilter = app.CreateBandpassFilter(lowPassFreq,highPassFreq,order,imageSize);
            
            % Apply bandpass filter to images
                
                if ~isfield(app.imagerDataInfo,'bandpassIndex')%if median filtered images not yet stored... create new index to store them
                    bandpassIndex = size(app.imagerData,1)+1;
                    
                    app.imagerDataInfo.bandpassIndex = bandpassIndex;%save value in info structure
                    app.imagerDataInfo2.bandpassIndex = bandpassIndex;
                else
                    bandpassIndex = app.imagerDataInfo.bandpassIndex;
                end
    
            %Apply bandpass to each frame
                %waitbar
                
                [ny,nx] = size(app.imagerData{1,1});%image size will be same for all
                
                wb = waitbar(0,'Filtering Image Series 1','name','Be Patient');%waitbar

                for f = 1:size(app.imagerData,2)
                    %take fourier transform of image
                    fftI = fft2(app.imagerData{1,f},2*ny+1,2*nx+1);%size is necessarily odd... nx on either side plus origin
                    fftI = fftshift(fftI);
                    
                    %apply filter in fourier domain
                    filteredImage = fftI.*bandpassFilter;
                    
                    %take inverse fourier transform
                    filteredImage = ifftshift(filteredImage);
                    filteredImage = ifft2(filteredImage,2*ny+1,2*nx+1);
                    
                    %store filtered image
                    app.imagerData{bandpassIndex,f} = real(filteredImage(1:ny,1:nx));
                    
                        if rem(f,5) == 0 %update waitbar
                        waitbar(f/size(app.imagerData,2),wb); 
                        end
                end
                close(wb);
                
                    %Set default window/level
                        %Can change within the app if wanted
                        %Same window/level used for image series 1 and 2 for bandpass filtering
                    a = min(app.imagerData{bandpassIndex,1},[],'all');
                    b = max(app.imagerData{bandpassIndex,1},[],'all');
                    
                    app.BandpassLowLevelEditField.Value = a;
                    app.BandpassHighLevelEditField.Value = b;

                    app.imagerDataInfo.WindowLevel(bandpassIndex,:) = [a,b];

                
                if ~isempty(app.imagerData2)%if loaded in do for image series 2 also...
                    
                    [ny,nx] = size(app.imagerData2{1,1});%image size will be same for all
                            wb = waitbar(0,'Filtering Image Series 2','name','Be Patient');%waitbar
                    
                    for f = 1:size(app.imagerData2,2)
                       %take fourier transform of image
                        fftI = fft2(app.imagerData2{1,f},2*ny+1,2*nx+1);%size is necessarily odd... nx on either side plus origin
                        fftI = fftshift(fftI);
                        
                       %apply filter in fourier domain
                        filteredImage = fftI.*bandpassFilter;
                        
                       %take inverse fourier transform
                        filteredImage = ifftshift(filteredImage);
                        filteredImage = ifft2(filteredImage,2*ny+1,2*nx+1);
                        
                       %store filtered image
                        app.imagerData2{bandpassIndex,f} = real(filteredImage(1:ny,1:nx));
                        
                            if rem(f,5) == 0%update waitbar    
                            waitbar(f/size(app.imagerData,2),wb); 
                            end
                    end
                    close(wb);
                 
                app.imagerDataInfo2.WindowLevel(bandpassIndex,:) = app.imagerDataInfo.WindowLevel(bandpassIndex,:);
                end
                     
                %add option to view bandpass filtered images in dropdown
                    if ~ismember('Bandpass',app.ImagetoViewDropDown.Items)
                        nItems = numel(app.ImagetoViewDropDown.Items);
                        app.ImagetoViewDropDown.Items{nItems+1} = 'Bandpass';
                    end
    
                %change plots to view median filter    
                app.ImagetoViewDropDown.Value = 'Bandpass';
                UpdateAxis1(app);
                UpdateAxis2(app);
        end

        % Button pushed function: BandpassImageContrastButton, 
        % ...and 2 other components
        function ImageContrastButtonPushed(app, event)
% Launch new figure with imcontrast tool. Current version requires user to manually enter window/level settings             
      
    f = app.ProjectionSlider.Value;%Current frame
    w = 0;%warning
           %Determine which pushbutton pressed and do appropriate action
            if any(contains(event.Source.Text,'Series 1','IgnoreCase',true))
            %series 1 imcontrast button pushed
                if ~isempty(app.imagerData)%Only possible if data is loaded in.
                    im4Contrast = app.imagerData{1,f};
                    titleStr = 'Series 1';
                else 
                    warning('Must load in image series first.');
                    w = 1;
                end
            elseif any(contains(event.Source.Text','Series 2','IgnoreCase',true))
            %series 2 imcontrast button pushed
                if ~isempty(app.imagerData2)%Only possible if data is loaded in.
                    im4Contrast = app.imagerData2{1,f};
                    titleStr = 'Series 2';
                else 
                    warning('Must load in image series first.');
                    w=1;
                end
            elseif any(contains(event.Source.Text,'Bandpass','IgnoreCase',true))%bandpass imcontrast button pushed
                if isfield(app.imagerDataInfo,'bandpassIndex')
                    bI = app.imagerDataInfo.bandpassIndex;
                    im4Contrast = app.imagerData{bI,f};
                    titleStr = 'Bandpass';
                else
                    warning('Must bandpass filter first.')
                    w=1;
                end
            else 
                error('Not correctly choosing between pushbuttons')
            end

    if ~w %if no warning            
   %New figure outside of app
    fig = figure('name',['Image For Window/Level Determination: ',titleStr]);
    imagesc(im4Contrast)%unfiltered images
    fig.CurrentAxes;colormap gray;axis('image');
    title([titleStr,': Determine Window Level with ImContrast Tool & Manually enter in App.'])
    imcontrast(fig)   
    end
                  
        end

        % Button pushed function: NewTrackingObjectButton
        function NewTrackingObjectButtonPushed(app, event)
% Creates new tracking object
           %index of new object
            oI = length(app.trackingData)+1;
           %Add new object to drop down options
            app.CurrentObjectDropDown.Items{oI} = ['Object ',num2str(oI)];%add to items
            app.CurrentObjectDropDown.Value = ['Object ',num2str(oI)];%Set value to it.
            
            %Initiate structure:
            app.trackingData{oI}.name = '';
            app.trackingData{oI}.referenceFrame = app.imagerDataInfo.referenceFrame;

                %Booleans of progress
                app.trackingData{oI}.isTracked = 0;%boolean of if has been tracked.
                app.trackingData{oI}.isAltered = 0;%boolean of if has been alterred since tracking.
                app.trackingData{oI}.trackingCenterDefined1 = 0;%boolean of if tracking centre has been defined
                app.trackingData{oI}.trackingCenterDefined2 = 0;%boolean of if tracking centre has been defined
                app.trackingData{oI}.trackingAreaDefined1 = 0;%boolean of if tracking area has been defined
                app.trackingData{oI}.trackingAreaDefined2 = 0;%boolean of if tracking area has been defined
            UpdateTrackPanel(app);
           %Reset Axis
            UpdateAxis1(app);
            UpdateAxis2(app);
        end

        % Value changed function: ReferenceFrameSpinner
        function ReferenceFrameSpinnerValueChanged(app, event)
            value = app.ReferenceFrameSpinner.Value;
            
            if value>app.nFrames
                warning('reference frame must be within loaded frames. Auto-set reference frame to last frame');
                value = app.nFrames;
                app.ReferenceFrameSpinner.Value = value;
            end
            
            app.imagerDataInfo.referenceFrame = value;
            app.imagerDataInfo2.referenceFrame = value;

            %also change currently viewed frame
            app.ProjectionSlider.Value = value;
            app.ProjectionSliderValueChanged(app);
        end

        % Value changed function: CurrentObjectDropDown
        function CurrentObjectDropDownValueChanged(app, event)
%Change display of Track based on object drop down.        
           %Remove Anything on axis
            UpdateAxis1(app);
            UpdateAxis2(app);
           %Set the track Panel to the right values
            UpdateTrackPanel(app);
           
        end

        % Button down function: TrackingTab
        function TrackingTabButtonDown(app, event)
%Initiate parameters when entering tracking tab            
            app.ImagesforAutoTrackingDropDown.Items = app.ImagetoViewDropDown.Items;
            app.ImagesforAutoTrackingDropDown.Value = app.ImagetoViewDropDown.Value;
            
           %set reference frame to default
            app.ReferenceFrameSpinnerValueChanged(app);
           
           %Update the track panel in case things loaded in 
            UpdateTrackPanel(app);
        end

        % Button pushed function: DefineTemplateSeries1Button, 
        % ...and 1 other component
        function DefineTrackingAreaButtonPushed(app, event)
%Defines contour of area to track using auto-tracking technique.

           %Ensure viewed frame is the reference frame
            app.ProjectionSlider.Value = app.imagerDataInfo.referenceFrame;
            app.ProjectionSliderValueChanged();

           % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')

           %Decide if series 1 or series 2 button pushed...
            if any(contains(event.Source.Text,'Series 1'))%series 1 button pushed
                temp = app.UIAxes.XColor;%for returning to default 
                app.UIAxes.XColor = [0 0.7 0];
                app.UIAxes.YColor = [0 0.7 0];
                
                series = 1;
            elseif any(contains(event.Source.Text,'Series 2'))%series 2 button pushed
                temp = app.UIAxes2.XColor;
                app.UIAxes2.XColor = [0 0.7 0];
                app.UIAxes2.YColor = [0 0.7 0];
                
                series = 2;
            else
                error('This code isnt working properly')
            end
    
           % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            
            disp(['Click to define rectangle of the object to track on the green axis. Code auto-creates',...
                  ' a rectangle based on the min and max values input. Click until happy then press RETURN']);
            [xContour,yContour]=ginput;

            app.UIFigure.HandleVisibility = fhv;        % return original state

            oI = CurrentObject(app);%object index
    
            if ~isempty(xContour)%if something was input

                %Plot box drawn: Will be removed at next call of UpdateAxis
                switch series
                    case 1          %series 1

                       %clear anything existing
                        app.trackingData{oI}.trackingArea1 = zeros(length(xContour),2);
                       %Store data
                        app.trackingData{oI}.trackingArea1(:,1) = xContour;%x-data- series 1;
                        app.trackingData{oI}.trackingArea1(:,2) = yContour;%y-data- series 1;
                        
                       %Overlay on plot
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.trackingData{oI}.trackingArea1(:,1),...
                            app.trackingData{oI}.trackingArea1(:,2),'r.','MarkerSize',14)
                        hold(app.UIAxes,'off');

                       %return color of axis
                        app.UIAxes.XColor = temp;
                        app.UIAxes.YColor = temp;
                       %Indicate tracking area has been defined.
                        app.trackingData{oI}.trackingAreaDefined1 = 1;

                    case 2          %series 2

                       %clear anything existing
                        app.trackingData{oI}.trackingArea2 = zeros(length(xContour),2);
                       %Store data
                        app.trackingData{oI}.trackingArea2(:,1) = xContour;%x-data- series 2;
                        app.trackingData{oI}.trackingArea2(:,2) = yContour;%y-data- series 2;

                        hold(app.UIAxes2,'on');
                        plot(app.UIAxes2,app.trackingData{oI}.trackingArea2(:,1),...
                            app.trackingData{oI}.trackingArea2(:,2),'r.','MarkerSize',14)
                        hold(app.UIAxes2,'off');

                       %return color of axis
                        app.UIAxes2.XColor = temp;
                        app.UIAxes2.YColor = temp;
                        
                       %Indicate tracking area has been defined.
                        app.trackingData{oI}.trackingAreaDefined2 = 1;
                        
                    otherwise
                        error('Not properly assigning between Series 1 and 2');
                end
            end

            %If was previously tracked, and now values are changed, is
            %altered is true
            if app.trackingData{oI}.isTracked
                app.trackingData{oI}.isAltered = 1;
            end

            %update layout of panel.
            UpdateTrackPanel(app)
            
        end

        % Button pushed function: DefineObjectCenterSeries1Button, 
        % ...and 1 other component
        function DefineTrackingObjectCenterButtonPushed(app, event)
%Prompts user to select the center point of object to track.

%Get and update any existing parameters. This is particularly necessary for
%when first object is created. otherwise parameters of structure are not
%contained
getTrackParameters(app);

        %Ensure viewed frame is the reference frame
            app.ProjectionSlider.Value = app.imagerDataInfo.referenceFrame;
            app.ProjectionSliderValueChanged();

           % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')

           %Decide if series 1 or series 2 button pushed...
            if any(contains(event.Source.Text,'Series 1'))%series 1 button pushed
                temp = app.UIAxes.XColor;%for returning to default 
                app.UIAxes.XColor = [0 0.7 0];
                app.UIAxes.YColor = [0 0.7 0];
                
                series = 1;
            elseif any(contains(event.Source.Text,'Series 2'))%series 2 button pushed
                temp = app.UIAxes2.XColor;
                app.UIAxes2.XColor = [0 0.7 0];
                app.UIAxes2.YColor = [0 0.7 0];
                
                series = 2;
            else
                error('This code isnt working properly')
            end
    
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            
            disp('Click to define center of the object to track on green axis.');
            [xContour,yContour]=ginput(1);
            
            app.UIFigure.HandleVisibility = fhv;        % return original state

            oI = CurrentObject(app);%object index
    
            if ~isempty(xContour)%if something was input

               %Store data
                app.trackingData{oI}.trackingCenter(1,1,series) = xContour;%x-data- series 1;
                app.trackingData{oI}.trackingCenter(1,2,series) = yContour;%y-data- series 1;

               %Plot box drawn: Will be removed at next call of UpdateAxis
                switch series
                    case 1          %series 1
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.trackingData{oI}.trackingCenter(:,1,1),...
                            app.trackingData{oI}.trackingCenter(:,2,1),'bo','MarkerSize',15,'LineWidth',1.7)
                        hold(app.UIAxes,'off');

                       %return color of axis
                        app.UIAxes.XColor = temp;
                        app.UIAxes.YColor = temp;
                       %Indicate tracking area has been defined.
                        app.trackingData{oI}.trackingCenterDefined1 = 1;

                    case 2          %series 2
                        hold(app.UIAxes2,'on');
                        plot(app.UIAxes2,app.trackingData{oI}.trackingCenter(:,1,2),...
                            app.trackingData{oI}.trackingCenter(:,2,2),'bo','MarkerSize',15,'LineWidth',1.7)
                        hold(app.UIAxes2,'off');

                       %return color of axis
                        app.UIAxes2.XColor = temp;
                        app.UIAxes2.YColor = temp;
                        
                       %Indicate tracking area has been defined.
                        app.trackingData{oI}.trackingCenterDefined2 = 1;
                        
                    otherwise
                        error('Not properly assigning between Series 1 and 2');
                end
            end

            %If was previously tracked, and now values are changed, is
            %altered is true
            if app.trackingData{oI}.isTracked
                app.trackingData{oI}.isAltered = 1;
            end

           %update layout of panel.
            UpdateTrackPanel(app)
            
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            %Store data in current object info.
            oI = CurrentObject(app);
            app.trackingData{oI}.name = app.NameEditField.Value;

            UpdateTrackPanel(app)
        end

        % Value changed function: ObjectCenterSeries1EditField, 
        % ...and 1 other component
        function ObjectCenterSeries1EditFieldValueChanged(app, event)
          %Change boolean of trackingCenterDefind
            oI = CurrentObject(app);
          %get values
            app.trackingData{oI}.trackingCenter(1,1,1) = app.ObjectCenterSeries1EditField.Value;
            app.trackingData{oI}.trackingCenter(1,2,1) = app.ObjectCenterSeries1EditField2.Value;
            app.trackingData{oI}.trackingCenterDefined1 = 1;

          %If was previously tracked, and now values are changed, is
            %altered is true
            if app.trackingData{oI}.isTracked
                app.trackingData{oI}.isAltered = 1;
            end

          %Update Panel
            UpdateTrackPanel(app)
          %Show on plot
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.trackingData{oI}.trackingCenter(:,1,1),...
                app.trackingData{oI}.trackingCenter(:,2,1),'bo','MarkerSize',15,'LineWidth',1.7)
            hold(app.UIAxes,'off');
        end

        % Value changed function: ObjectCenterSeries2EditField, 
        % ...and 1 other component
        function ObjectCenterSeries2EditFieldValueChanged(app, event)
          %Change boolean of trackingCenterDefind
            oI = CurrentObject(app);
           %get values
            app.trackingData{oI}.trackingCenter(1,1,2) = app.ObjectCenterSeries2EditField.Value;
            app.trackingData{oI}.trackingCenter(1,2,2) = app.ObjectCenterSeries2EditField2.Value;
            app.trackingData{oI}.trackingCenterDefined2 = 1;

          %Update Values
            UpdateTrackPanel(app)
          %Show on plot
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,app.trackingData{oI}.trackingCenter(:,1,2),...
                app.trackingData{oI}.trackingCenter(:,2,2),'bo','MarkerSize',15,'LineWidth',1.7)
            hold(app.UIAxes2,'off');
        end

        % Button pushed function: TrackButton
        function TrackButtonPushed(app, event)
%Track the current object
        %First Update Track Panel... This ensure isReadyToTrack boolean is
        %current...
        UpdateTrackPanel(app);
        oI = CurrentObject(app);%current object index
            
            if ~app.trackingData{oI}.isReadyToTrack
                error('Define all necessary parameters before tracking');
            end
        
            app.trackingData{oI}.autoTrackedPts = autoTrackNormXCorr2(app);

         %initiate adjusted tracking to auto-tracking
            app.trackingData{oI}.adjTrackedPts(:,:,:) = app.trackingData{oI}.autoTrackedPts;

         %Update booleans... is tracked!
            app.trackingData{oI}.isTracked = 1;
            app.ShowTrackedLeadsCheckBox.Value = 1;
            %Just tracked... nothing can be altered.
            app.trackingData{oI}.isAltered = 0;


         UpdateTrackPanel(app)
         UpdateAxis1(app);
         UpdateAxis2(app);   
        end

        % Button pushed function: AdjustButton
        function AdjustButtonPushed(app, event)

            %Find what object is supposed to be adjusted
            obj2adj = find(string(app.leadtoadjustDropDown.Items)==app.leadtoadjustDropDown.Value);
            
            if ~app.trackingData{obj2adj}.isTracked
                error('Must auto-track lead before adjusting.')
            end

            %Decide if adjusting series 1 or series 2
            if any(contains(app.SeriestoadjustDropDown.Value,'1'))%series 1 button pushed
                temp = app.UIAxes.XColor;%for returning to default 
                app.UIAxes.XColor = [0 0.7 0];
                app.UIAxes.YColor = [0 0.7 0];
                
                series = 1;
            elseif any(contains(app.SeriestoadjustDropDown.Value,'2'))%series 2 button pushed
                temp = app.UIAxes2.XColor;
                app.UIAxes2.XColor = [0 0.7 0];
                app.UIAxes2.YColor = [0 0.7 0];
                
                series = 2;
            else
                error('This code isnt working properly')
            end
    
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            
            disp('Click adjust the specified data point on the green axis.');
            [xAdjusted,yAdjusted]=ginput(1);
            
            app.UIFigure.HandleVisibility = fhv;        % return original state

            f = app.ProjectionSlider.Value;%Current frame
          
            if ~isempty(xAdjusted)%if something was input
               %Store data
                app.trackingData{obj2adj}.adjTrackedPts(f,1,series) = xAdjusted;%x-data- series 1;
                app.trackingData{obj2adj}.adjTrackedPts(f,2,series) = yAdjusted;%y-data- series 1;

               %Plot box drawn: Will be removed at next call of UpdateAxis
                switch series
                    case 1          %series 1
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,app.trackingData{obj2adj}.adjTrackedPts(f,1,1),...
                            app.trackingData{obj2adj}.adjTrackedPts(f,2,1),'r^','MarkerSize',15,'LineWidth',1.7)
                        hold(app.UIAxes,'off');

                       %return color of axis
                        app.UIAxes.XColor = temp;
                        app.UIAxes.YColor = temp;
                       %Indicate tracking area has been defined.
                        app.trackingData{obj2adj}.trackingCenterDefined1 = 1;

                    case 2          %series 2
                        hold(app.UIAxes2,'on');
                        plot(app.UIAxes2,app.trackingData{obj2adj}.adjTrackedPts(f,1,2),...
                            app.trackingData{obj2adj}.adjTrackedPts(f,2,2),'r^','MarkerSize',15,'LineWidth',1.7)
                        hold(app.UIAxes2,'off');

                       %return color of axis
                        app.UIAxes2.XColor = 'k';
                        app.UIAxes2.YColor = 'k';
                        
                    otherwise
                        error('Not properly assigning between Series 1 and 2');
                end
            end

           %update layout of panel.
            UpdateTrackPanel(app)
        end

        % Button pushed function: PlayFramesButton
        function PlayFramesButtonPushed(app, event)
            %Plays each frame of the images starting with frame one
           
            cf = app.ProjectionSlider.Value;

            for f = 1:app.nFrames
                app.ProjectionSlider.Value = f;
                ProjectionSliderValueChanged(app);
                pause(app.FrameDelaysEditField.Value);
            end
            
            app.ProjectionSlider.Value = cf;%restore to default
            ProjectionSliderValueChanged(app);

        end

        % Button down function: TriangulateTab
        function TriangulateTabButtonDown(app, event)
%Execute when change to triangulate tab
            
          %Ensure pixel size numeric field matches that defined earlier
            app.PixelSizemmEditField.Value = app.PixelSizemmEditField.Value;
            
        end

        % Button pushed function: TriangulateButton
        function TriangulateButtonPushed(app, event)
%Triangulate all tracked objects
        %This code assumes a gantry angle of 45 degrees (image panels at 
        % 180 and 270) then rotate results to true lab frame.
           %Note: the sup-inf direction must correspond with the X axis in 
           % the images for this triangulation to work.
           % I.E. patient must be head-first on the couch.
s=0;%boolean

            %Assume gantry = 45; imagers at 180 & 270.
            SID = app.SourcetoImageDistancemmEditField.Value;%Source to image distance
            kVSourcePosition = [SID, 0, 0];%XYZ
            kVSourcePosition2 = [0, -SID, 0];
            sPixel = app.PixelSizemmEditField.Value;%pixel size

            %Get gantry angle for rotating data after triangulated
            gantryAngle = app.GantryAngledegEditField.Value;
            theta = (gantryAngle-45)*pi/180;%-45 accounts for assumption of 45deg.

            %Rotation matrix
            Rz = [cos(theta) -1*sin(theta) 0; sin(theta) cos(theta) 0; 0 0 1];

            for oI = 1:length(app.trackingData)%For each object
                %Check to ensure the data is ready to be triangulated
                if isfield(app.trackingData{oI},'isTracked') && app.trackingData{oI}.isTracked
                    %Assume head first such that x-axis is sup-inf
                    Zref1 = (384.5-round(app.trackingData{oI}.adjTrackedPts(:,1,1)))*sPixel;
                    Zref2 = (384.5-round(app.trackingData{oI}.adjTrackedPts(:,1,2)))*sPixel;

                    Yref1 = (round(app.trackingData{oI}.adjTrackedPts(:,2,1))-512.5)*sPixel;
                    Xref2 = (round(app.trackingData{oI}.adjTrackedPts(:,2,2))-512.5)*sPixel;
                    
                    unitVec = ones(size(Yref1));

                    pos = [-876*unitVec, Yref1, Zref1];
                    pos2 = [Xref2, 876*unitVec, Zref2];
                   
                    % Triangulate from ray projection
                    app.trackingData{oI}.totalMotion = zeros(size(pos));%preallocate
                    for f=1:size(pos,1)  
                        [~, ~, c, d] = app.DistBetween2Segment(kVSourcePosition, pos(f,1:3), kVSourcePosition2, pos2(f,1:3)); %Closests Point Along Ray Projection Lines
                        triangulatedPt(1,:)=(c+d)/2;  %Midpoint of Closest Points

                        %Rotate result to true labframe - accounts for
                        %gantry angle - i.e. angle of imagers
                        app.trackingData{oI}.totalMotion(f,:) = Rz*triangulatedPt(1,:)';
                        app.trackingData{oI}.gantryAngle = gantryAngle;
                    end
    
                    app.trackingData{oI}.isTriangulated = 1;
                    disp(['Successfully triangulated Object ',num2str(oI),': ',app.trackingData{oI}.name])
                    s=1;
                    
                    app.trackingData{oI}.totalMotionZeroMean = ...
                        app.trackingData{oI}.totalMotion - mean(app.trackingData{oI}.totalMotion,1);
                    
                    %Also take fft of data for visualization in separate motion tab
                    for k = 1:3
                       app.trackingData{oI}.fftAmplitudeData(:,k) = app.AmplitudeSpectrum(app.trackingData{oI}.totalMotion(:,k));
                    end
                else
                    app.trackingData{oI}.isTriangulated = 0;
                    disp(['UNSUCCESSFUL triangulating Object ',num2str(oI),'. Track Object and Re-try'])
                end
            end      
            %if any were successfully triangulated change button color to
            %green
            if s
                app.TriangulateButton.BackgroundColor = [0.8 0.9 0.8];%green
            end

        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
%Changes image frame to the next one
            %round value to nearest integer - should be redundant
            app.ProjectionSlider.Value = round(app.ProjectionSlider.Value);
            
            if app.ProjectionSlider.Value < app.nFrames
                app.ProjectionSlider.Value = app.ProjectionSlider.Value+1;
            elseif app.ProjectionSlider.Value == app.nFrames
                app.ProjectionSlider.Value = 1;
            end

           %update axis
            UpdateAxis1(app);
            UpdateAxis2(app);
        end

        % Button pushed function: PreviousFrameButton
        function PreviousFrameButtonPushed(app, event)
%Changes image frame to the previous one
           %round value to nearest integer - should be redundant
            app.ProjectionSlider.Value = round(app.ProjectionSlider.Value);
            
            if app.ProjectionSlider.Value > 1
                app.ProjectionSlider.Value = app.ProjectionSlider.Value-1;
            elseif app.ProjectionSlider.Value == 1
                app.ProjectionSlider.Value = app.nFrames;
            end
            
           %update axis
            UpdateAxis1(app);
            UpdateAxis2(app);
        end

        % Value changed function: ImagesforAutoTrackingDropDown
        function ImagesforAutoTrackingDropDownValueChanged(app, event)

            app.ImagetoViewDropDown.Value = app.ImagesforAutoTrackingDropDown.Value;

            app.ProjectionSliderValueChanged(app);
        end

        % Button pushed function: NewRespiratoryMotionSurrogateButton
        function NewRespiratoryMotionSurrogateButtonPushed(app, event)
            % Prompt user to create a motion surrogate.
                disp('Draw a rectangular region to track edge within. The initial rectangle must be drawn horizontally.... then can be rotated')

              %Defaultly uses images that are being viewed
              if any(contains(app.ImageDropDown.Value,'Series 1','IgnoreCase',true))
                %Delete any existing rectangles
                if isfield(app.motionSurrogate,'roi')
                    delete(app.motionSurrogate.roi);
                end
                %create new
                app.motionSurrogate.roi = drawrectangle(app.UIAxes,'Rotatable',1);
              elseif any(contains(app.ImageDropDown.Value,'Series 2','IgnoreCase',true))
                if isfield(app.motionSurrogate,'roi')
                    delete(app.motionSurrogate.roi);
                end
                app.motionSurrogate.roi = drawrectangle(app.UIAxes2,'Rotatable',1);
              else
                  error('incorrectly choosing between series 1 and 2')
              end
            %Add listener to rectangle function
            addlistener(app.motionSurrogate.roi,'ROIMoved',@app.MotionSurrogateRoiMoved);
            addlistener(app.motionSurrogate.roi,'DeletingROI',@app.MotionSurrogateRoiMoved);
            % Using ROI create motion surrogate model
            AnalyzeMotions(app) 

        end

        % Value changed function: HeartRateEditField, LEditField, 
        % ...and 1 other component
        function FcutFormulaValueChangedValueChanged(app, event)
            
            %If change one of the inputs to the Fcut formula, update the
            %value and plot.
            
            Fc = app.HeartRateEditField.Value/60; %bpm to beats per sec (Hz)
            
            Fr = 1/app.RespiratoryPeriodsecbreathEditField.Value;%Resp period to resp freq (Hz)

            L = app.LEditField.Value; %L
         
            Fcut = Fr + L*(Fc-Fr);
            
            %Write value into app
            app.FcutEditField.Value = round(100*Fcut)/100;
            
            %Update plot to show new Fcut value
            UpdateAxis4(app)
        end

        % Value changed function: FcutEditField
        function FcutEditFieldValueChanged(app, event)
            %Update plot to show new Fcut value
            UpdateAxis4(app)
        end

        % Button pushed function: SeparateMotionsButton
        function SeparateMotionsButtonPushed(app, event)
        %separate cardiac and respiratory motions
        s = 0;
          %Get Fcut value from app
            Fcut = app.FcutEditField.Value;
          %num frames
            N = app.nFrames;
          %Time vec
            samplingFreq = app.SamplingFrequencyHzEditField.Value;
            t = seconds(transpose(0:(N-1))/samplingFreq);

            for oI = 1:length(app.trackingData) %for each tracked object
                if isfield(app.trackingData{oI},'isTriangulated') && app.trackingData{oI}.isTriangulated %If it isn't a field won't throw error
                  % Extend data, apply tukey window, and zero pad
                    [motionDataPadded,dataVec,centerShift,tukeyWindow] = SignalPadding(app.trackingData{oI}.totalMotion);      

                     %Call ideal filtering function - uses an ideal high and low pass filter
                     [respiratoryMotionZeroPad,~,cardiacDataZeroPad,~] = ...
                         IdealFiltering(motionDataPadded,Fcut,samplingFreq);
                                                 %NOTE: highpass() and lowpass() are not ideal filters which leads to reconstruction errors.                                                 
                      %Cardiac motion - high freq
                        app.trackingData{oI}.cardiacMotion(:,:) = cardiacDataZeroPad(dataVec,:) + centerShift;%account for shift
                        app.trackingData{oI}.cardiacMotionZeroMean(:,:) = cardiacDataZeroPad(dataVec,:);%account for shift
                      %Respiratory motion - low freq
                        app.trackingData{oI}.respiratoryMotion(:,:) = respiratoryMotionZeroPad(dataVec,:)  + centerShift;%account for shift
                        app.trackingData{oI}.respiratoryMotionZeroMean(:,:) = respiratoryMotionZeroPad(dataVec,:);

                      %Also save parameters of filtering for later reference
                        app.trackingData{oI}.Fcut = Fcut;
                        app.trackingData{oI}.t = t;
                        app.trackingData{oI}.tukeyWindow = tukeyWindow;
                    s=1;%at least one successful
                end
            end

            if s; app.SeparateMotionsButton.BackgroundColor = [0.8 0.9 0.8];%green - success
            else; app.SeparateMotionsButton.BackgroundColor = [1 0 0];%red - failure
            end

VisualizeSeparatedMotions(app)
        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
            
            saveFolder = uigetdir();   
f=msgbox('Saving: Should take a minute or two');

       %Put all variables to save into S and then save at the end            
            %Save the files in a defined way.
            d = char(datetime('today','Format',"MMddyyyy"));
            
            identifier = app.FileIdentifierEditField.Value;

if ~isMATLABReleaseOlderThan("R2022b") % SOMETHING WEIRD WITH OLDER VERSIONS....
            %Deal with images
            parent1 = app.ImageDataSeries1SaveNode; 
            parent2 = app.ImageDataSeries2SaveNode;

       %First, for series 1 data
            exportI = false(size(app.imagerData,1),1);
            exportIndicesName = strings(size(app.imagerData,1),1);
            for k = 1:length(parent1.Parent.CheckedNodes)
                switch parent1.Parent.CheckedNodes(k).Text
                    case 'Series 1'
                        %Save all
                        exportI(:) = 1;

                        %Save names of images
                        exportIndicesName(1) = 'Unfiltered';

                        if isfield(app.imagerDataInfo,'medianIndex')
                           exportIndicesName(app.imagerDataInfo.medianIndex) = 'Median';
                        end

                        if isfield(app.imagerDataInfo,'bandpassIndex')
                           exportIndicesName(app.imagerDataInfo.bandpassIndex) = 'Bandpass';
                        end

                        break %all are saved so break out of for loop
                    case 'Unfiltered'
                        %add unfiltered to export    
                        exportI(1) = 1;         %always the first index
                        exportIndicesName(1) = 'Unfiltered';
                    case 'Median'
                        %add median to export 
                        exportI(app.imagerDataInfo.medianIndex) = 1;
                        exportIndicesName(app.imagerDataInfo.medianIndex) = 'Median';
                    case 'Bandpass'
                        %add bandpass to export
                        exportI(app.imagerDataInfo.bandpassIndex) = 1;
                        exportIndicesName(app.imagerDataInfo.bandpassIndex) = 'Bandpass';
                end
            end

            %Put corresponding data into structure to save...
            S.imagerDataInfo = app.imagerDataInfo;
            S.imagerData = app.imagerData(exportI,:);
            S.imagerDataInfo.exportI = exportI; %save for later reference
            S.imagerDataInfo.exportIndicesName = exportIndicesName;

        %Now, for series 2 data
            exportI2 = false(size(app.imagerData2,1),1);
            exportIndicesName2 = strings(size(app.imagerData2,1),1);
            for k = 1:length(parent2.Parent.CheckedNodes)
                switch parent2.Parent.CheckedNodes(k).Text
                    case 'Series 2'
                        %Save all
                        exportI2(:) = 1;

                        %Save names of images
                        exportIndicesName2(1) = 'Unfiltered';

                        if isfield(app.imagerDataInfo2,'medianIndex')
                           exportIndicesName2(app.imagerDataInfo2.medianIndex) = 'Median';
                        end

                        if isfield(app.imagerDataInfo2,'bandpassIndex')
                           exportIndicesName2(app.imagerDataInfo2.bandpassIndex) = 'Bandpass';
                        end

                        break %all are saved so break out of for loop
                    case 'Unfiltered'
                        %add unfiltered to export    
                        exportI2(1) = 1;         %always the first index
                        exportIndicesName2(1) = 'Unfiltered';
                    case 'Median'
                        %add median to export 
                        exportI2(app.imagerDataInfo2.medianIndex) = 1;
                        exportIndicesName2(app.imagerDataInfo2.medianIndex) = 'Median';
                    case 'Bandpass'
                        %add bandpass to export
                        exportI2(app.imagerDataInfo2.bandpassIndex) = 1;
                        exportIndicesName2(app.imagerDataInfo2.bandpassIndex) = 'Bandpass';
                end
            end

            %Put corresponding data into structure to save...
            S.imagerDataInfo2 = app.imagerDataInfo2;
            S.imagerData2 = app.imagerData2(exportI2,:);
            S.imagerDataInfo2.exportI2 = exportI2; %save for later reference
            S.imagerDataInfo2.exportIndicesName2 = exportIndicesName2;
end


      %Also save other things that are selected
            if app.TrackingDataCheckBox.Value %if selected to save
                S.trackingData = app.trackingData;
            end

            if app.MotionSurrogateCheckBox.Value %if selected to save
                S.motionSurrogate = app.motionSurrogate;
            end

           save([saveFolder,'\',d,'_',identifier,'_MotionTrackingAppData'],'-struct','S');

      %Finally save plots
            if app.SeparatedmotionsplotsCheckBox.Value
                opt.date = d;
                opt.saveFolder = saveFolder;
                opt.save = 1;
                opt.identifier = identifier;
                VisualizeSeparatedMotions(app,opt)
            end

            if app.TrackingMovieCheckBox.Value
                imI = ImageIndexLookup(app,app.ImagetoViewDropDown.Value);
                o.savePath = [saveFolder,'\',opt.date,'_',opt.identifier,'_TrackingMovieImager1'];
                
                %Extract tracked pts in format for fnc
                sss = size(app.trackingData{1}.adjTrackedPts);
                nLeads = length(app.trackingData);

                tData = zeros(sss(1),sss(2),nLeads);tData2 = zeros(sss(1),sss(2),nLeads);%Preallocate
                for k = 1:nLeads
                    tData(:,:,k) = app.trackingData{k}.adjTrackedPts(:,:,1);
                    tData2(:,:,k) = app.trackingData{k}.adjTrackedPts(:,:,2);
                end
                
                %save imager 1
                TrackingMovie(app.imagerData(imI,:),app.imagerDataInfo.WindowLevel(imI,:),tData,o)
                
                %save imager 2
                o.savePath = [saveFolder,'\',opt.date,'_',opt.identifier,'_TrackingMovieImager2'];
                TrackingMovie(app.imagerData2(imI,:),app.imagerDataInfo2.WindowLevel(imI,:),tData2,o)
            end

            if exist('f', 'var')
              delete(f);
              clear('f');
            end
 
            msgbox('All Done Saving');
            
        end

        % Button down function: SaveTab
        function SaveTabButtonDown(app, event)
            %Initiate the save options

            parent1 = app.ImageDataSeries1SaveNode; 
            parent2 = app.ImageDataSeries2SaveNode;
           %delete any children
            delete(parent1.Children)
            delete(parent2.Children)
           %Define new children 
            for k = 1:length(app.ImagetoViewDropDown.Items)
               uitreenode(parent1,"Text",app.ImagetoViewDropDown.Items{k});
               uitreenode(parent2,"Text",app.ImagetoViewDropDown.Items{k});
            end
        end

        % Value changed function: CoordinateforamplitudespectrumDropDown
        function CoordinateforamplitudespectrumDropDownValueChanged(app, event)
            %Update axis to view new selection
            UpdateAxis4(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {862, 862, 862};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {862, 862};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {353, '1x', 62};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout10 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout10);
            app.LoadFilterTab = uitab(app.TabGroup);
            app.LoadFilterTab.Title = 'Load/Filter';
            app.GridLayout12 = uigridlayout(app.LoadFilterTab);
            app.LoadFirstImageSeriesButton = uibutton(app.GridLayout12, 'state');
            app.LoadFirstImageSeriesButton.ValueChangedFcn = createCallbackFcn(app, @LoadFirstImageSeriesButtonPushed, true);
            app.LoadFirstImageSeriesButton.Text = {'Load'; 'First Image Series'};
            app.LoadSecondImageSeriesButton = uibutton(app.GridLayout12, 'state');
            app.LoadSecondImageSeriesButton.ValueChangedFcn = createCallbackFcn(app, @LoadSecondImageSeriesButtonPushed, true);
            app.LoadSecondImageSeriesButton.Text = {'Load'; 'Second Image Series'};
            app.UnfilteredWindowLevelPanel = uipanel(app.GridLayout12);
            app.UnfilteredWindowLevelPanel.Title = 'Unfiltered Window/Level';
            app.GridLayout13 = uigridlayout(app.UnfilteredWindowLevelPanel);
            app.Series1LowLevelEditFieldLabel = uilabel(app.GridLayout13);
            app.Series1LowLevelEditFieldLabel.Text = {'Series 1'; 'Low Level'};
            app.WindowLevel1LowEditField = uieditfield(app.GridLayout13, 'numeric');
            app.WindowLevel1LowEditField.RoundFractionalValues = 'on';
            app.WindowLevel1LowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLevelRawValueChanged, true);
            app.Series1HighLevelEditFieldLabel = uilabel(app.GridLayout13);
            app.Series1HighLevelEditFieldLabel.Text = {'Series 1'; 'High Level'};
            app.WindowLevel1HighEditField = uieditfield(app.GridLayout13, 'numeric');
            app.WindowLevel1HighEditField.RoundFractionalValues = 'on';
            app.WindowLevel1HighEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLevelRawValueChanged, true);
            app.WindowLevel1HighEditField.Value = 1000;
            app.Series1ImageContrastButton = uibutton(app.GridLayout13, 'push');
            app.Series1ImageContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ImageContrastButtonPushed, true);
            app.Series1ImageContrastButton.Text = {'Series 1'; 'Image Contrast'};
            app.Series2LowLevelEditFieldLabel = uilabel(app.GridLayout13);
            app.Series2LowLevelEditFieldLabel.Text = {'Series 2'; 'Low Level'};
            app.WindowLevel2LowEditField = uieditfield(app.GridLayout13, 'numeric');
            app.WindowLevel2LowEditField.RoundFractionalValues = 'on';
            app.WindowLevel2LowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLevelRawValueChanged, true);
            app.Series2HighLevelEditFieldLabel = uilabel(app.GridLayout13);
            app.Series2HighLevelEditFieldLabel.Text = {'Series 2'; 'High Level'};
            app.WindowLevel2HighEditField = uieditfield(app.GridLayout13, 'numeric');
            app.WindowLevel2HighEditField.RoundFractionalValues = 'on';
            app.WindowLevel2HighEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLevelRawValueChanged, true);
            app.WindowLevel2HighEditField.Value = 1000;
            app.Series2ImageContrastButton = uibutton(app.GridLayout13, 'push');
            app.Series2ImageContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ImageContrastButtonPushed, true);
            app.Series2ImageContrastButton.Text = {'Series 2'; 'Image Contrast'};
            app.DMedianFilterPanel = uipanel(app.GridLayout12);
            app.DMedianFilterPanel.Title = '2D Median Filter';
            app.GridLayout18 = uigridlayout(app.DMedianFilterPanel);
            app.windowsizeSpinnerLabel = uilabel(app.GridLayout18);
            app.windowsizeSpinnerLabel.Text = 'window size';
            app.windowsizeSpinner = uispinner(app.GridLayout18);
            app.windowsizeSpinner.Value = 3;
            app.MedianFilterButton = uibutton(app.GridLayout18, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.DBandpassFilterPanel = uipanel(app.GridLayout12);
            app.DBandpassFilterPanel.Title = '2D Bandpass Filter';
            app.GridLayout19 = uigridlayout(app.DBandpassFilterPanel);
            app.PassbandHighEditFieldLabel = uilabel(app.GridLayout19);
            app.PassbandHighEditFieldLabel.Text = 'Passband High';
            app.PassbandHighEditField = uieditfield(app.GridLayout19, 'numeric');
            app.PassbandHighEditField.Limits = [0 Inf];
            app.PassbandHighEditField.Value = 500;
            app.PassbandLowEditFieldLabel = uilabel(app.GridLayout19);
            app.PassbandLowEditFieldLabel.Text = 'Passband Low';
            app.PassbandLowEditField = uieditfield(app.GridLayout19, 'numeric');
            app.PassbandLowEditField.Limits = [0 Inf];
            app.PassbandLowEditField.Value = 100;
            app.BandpassLowLevelEditFieldLabel = uilabel(app.GridLayout19);
            app.BandpassLowLevelEditFieldLabel.Text = {'Bandpass '; 'Low Level'};
            app.BandpassLowLevelEditField = uieditfield(app.GridLayout19, 'numeric');
            app.BandpassLowLevelEditField.RoundFractionalValues = 'on';
            app.BandpassLowLevelEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLevelBandpassChanged, true);
            app.BandpassLowLevelEditField.Value = -500;
            app.BandpassHighLevelEditFieldLabel = uilabel(app.GridLayout19);
            app.BandpassHighLevelEditFieldLabel.Text = {'Bandpass/'; 'High Level'};
            app.BandpassHighLevelEditField = uieditfield(app.GridLayout19, 'numeric');
            app.BandpassHighLevelEditField.RoundFractionalValues = 'on';
            app.BandpassHighLevelEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLevelBandpassChanged, true);
            app.BandpassHighLevelEditField.Value = 500;
            app.BandpassImageContrastButton = uibutton(app.GridLayout19, 'push');
            app.BandpassImageContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ImageContrastButtonPushed, true);
            app.BandpassImageContrastButton.Text = {'Bandpass'; 'Image'; 'Contrast'};
            app.BandpassFilterButton = uibutton(app.GridLayout19, 'state');
            app.BandpassFilterButton.ValueChangedFcn = createCallbackFcn(app, @BandPassFilterButtonPushed, true);
            app.BandpassFilterButton.Text = 'Bandpass Filter';
            app.OrderSpinnerLabel = uilabel(app.GridLayout19);
            app.OrderSpinnerLabel.Text = 'Order';
            app.OrderSpinner = uispinner(app.GridLayout19);
            app.OrderSpinner.Limits = [0 Inf];
            app.OrderSpinner.RoundFractionalValues = 'on';
            app.OrderSpinner.Value = 5;
            app.Image = uiimage(app.GridLayout12);
            app.Image.ImageSource = 'van-rad-med-logo.png';
            app.LoadTrackingButton = uibutton(app.GridLayout12, 'push');
            app.LoadTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrackingButtonPushed, true);
            app.LoadTrackingButton.Text = 'Load Tracking';
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.TrackingTab.ButtonDownFcn = createCallbackFcn(app, @TrackingTabButtonDown, true);
            app.GridLayout16 = uigridlayout(app.TrackingTab);
            app.TrackingSettingsPanel = uipanel(app.GridLayout16);
            app.TrackingSettingsPanel.Title = 'Tracking Settings';
            app.GridLayout17 = uigridlayout(app.TrackingSettingsPanel);
            app.AutomatedTrackingDropDownLabel = uilabel(app.GridLayout17);
            app.AutomatedTrackingDropDownLabel.Text = 'Automated Tracking';
            app.AutomatedTrackingDropDown = uidropdown(app.GridLayout17);
            app.AutomatedTrackingDropDown.Items = {'Normalized Cross Correlation'};
            app.AutomatedTrackingDropDown.Value = 'Normalized Cross Correlation';
            app.LimitTrackingSearchRangeCheckBox = uicheckbox(app.GridLayout17);
            app.LimitTrackingSearchRangeCheckBox.Text = 'Limit Tracking Search Range';
            app.MaximumTotalDisplacementmmLabel = uilabel(app.GridLayout17);
            app.MaximumTotalDisplacementmmLabel.Text = {'Maximum Displacement From'; 'Reference Position (Pixels)'};
            app.MaximumDisplacementFromReferencePositionPixelsEditField = uieditfield(app.GridLayout17, 'numeric');
            app.MaximumDisplacementFromReferencePositionPixelsEditField.Limits = [0 Inf];
            app.MaximumDisplacementFromReferencePositionPixelsEditField.RoundFractionalValues = 'on';
            app.MaximumDisplacementFromReferencePositionPixelsEditField.Value = 100;
            app.ImagesforAutoTrackingDropDownLabel = uilabel(app.GridLayout17);
            app.ImagesforAutoTrackingDropDownLabel.Text = 'Images for Auto-Tracking';
            app.ImagesforAutoTrackingDropDown = uidropdown(app.GridLayout17);
            app.ImagesforAutoTrackingDropDown.Items = {'Unfiltered'};
            app.ImagesforAutoTrackingDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagesforAutoTrackingDropDownValueChanged, true);
            app.ImagesforAutoTrackingDropDown.Value = 'Unfiltered';
            app.ReferenceFrameSpinnerLabel = uilabel(app.GridLayout17);
            app.ReferenceFrameSpinnerLabel.Text = 'Reference Frame';
            app.ReferenceFrameSpinner = uispinner(app.GridLayout17);
            app.ReferenceFrameSpinner.Limits = [1 Inf];
            app.ReferenceFrameSpinner.RoundFractionalValues = 'on';
            app.ReferenceFrameSpinner.ValueChangedFcn = createCallbackFcn(app, @ReferenceFrameSpinnerValueChanged, true);
            app.ReferenceFrameSpinner.Value = 1;
            app.TrackPanel = uipanel(app.GridLayout16);
            app.TrackPanel.Title = 'Track';
            app.GridLayout20 = uigridlayout(app.TrackPanel);
            app.CurrentObjectDropDownLabel = uilabel(app.GridLayout20);
            app.CurrentObjectDropDownLabel.Text = 'Current Object';
            app.CurrentObjectDropDown = uidropdown(app.GridLayout20);
            app.CurrentObjectDropDown.Items = {'Object 1'};
            app.CurrentObjectDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrentObjectDropDownValueChanged, true);
            app.CurrentObjectDropDown.Value = 'Object 1';
            app.NewTrackingObjectButton = uibutton(app.GridLayout20, 'push');
            app.NewTrackingObjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewTrackingObjectButtonPushed, true);
            app.NewTrackingObjectButton.Text = 'New Tracking Object';
            app.NameEditFieldLabel = uilabel(app.GridLayout20);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout20, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.ObjectCenterSeries1EditField = uieditfield(app.GridLayout20, 'numeric');
            app.ObjectCenterSeries1EditField.ValueChangedFcn = createCallbackFcn(app, @ObjectCenterSeries1EditFieldValueChanged, true);
            app.ObjectCenterSeries2EditField = uieditfield(app.GridLayout20, 'numeric');
            app.ObjectCenterSeries2EditField.ValueChangedFcn = createCallbackFcn(app, @ObjectCenterSeries2EditFieldValueChanged, true);
            app.DefineObjectCenterSeries1Button = uibutton(app.GridLayout20, 'push');
            app.DefineObjectCenterSeries1Button.ButtonPushedFcn = createCallbackFcn(app, @DefineTrackingObjectCenterButtonPushed, true);
            app.DefineObjectCenterSeries1Button.Text = {'Define Object '; 'Center (Series 1)'};
            app.DefineObjectCenterSeries2Button = uibutton(app.GridLayout20, 'push');
            app.DefineObjectCenterSeries2Button.ButtonPushedFcn = createCallbackFcn(app, @DefineTrackingObjectCenterButtonPushed, true);
            app.DefineObjectCenterSeries2Button.Text = {'Define Object'; 'Center (Series 2)'};
            app.DefineTemplateSeries1Button = uibutton(app.GridLayout20, 'push');
            app.DefineTemplateSeries1Button.ButtonPushedFcn = createCallbackFcn(app, @DefineTrackingAreaButtonPushed, true);
            app.DefineTemplateSeries1Button.Text = {'Define Template '; '(Series 1)'};
            app.DefineTemplateSeries2Button = uibutton(app.GridLayout20, 'push');
            app.DefineTemplateSeries2Button.ButtonPushedFcn = createCallbackFcn(app, @DefineTrackingAreaButtonPushed, true);
            app.DefineTemplateSeries2Button.Text = {'Define Template '; '(Series 2)'};
            app.TrackButton = uibutton(app.GridLayout20, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @TrackButtonPushed, true);
            app.TrackButton.Text = 'Track';
            app.ObjectCenterSeries1EditField2 = uieditfield(app.GridLayout20, 'numeric');
            app.ObjectCenterSeries1EditField2.ValueChangedFcn = createCallbackFcn(app, @ObjectCenterSeries1EditFieldValueChanged, true);
            app.ObjectCenterSeries2EditField2 = uieditfield(app.GridLayout20, 'numeric');
            app.ObjectCenterSeries2EditField2.ValueChangedFcn = createCallbackFcn(app, @ObjectCenterSeries2EditFieldValueChanged, true);
            app.AdjustTrackingPanel = uipanel(app.GridLayout16);
            app.AdjustTrackingPanel.Title = 'Adjust Tracking';
            app.TabGroup2 = uitabgroup(app.AdjustTrackingPanel);
            app.ManualAdjustTab = uitab(app.TabGroup2);
            app.ManualAdjustTab.Title = 'Manual Adjust';
            app.GridLayout22 = uigridlayout(app.ManualAdjustTab);
            app.leadtoadjustDropDownLabel = uilabel(app.GridLayout22);
            app.leadtoadjustDropDownLabel.Text = 'lead to adjust';
            app.leadtoadjustDropDown = uidropdown(app.GridLayout22);
            app.AdjustButton = uibutton(app.GridLayout22, 'push');
            app.AdjustButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustButtonPushed, true);
            app.AdjustButton.Text = 'Adjust';
            app.SeriestoadjustDropDownLabel = uilabel(app.GridLayout22);
            app.SeriestoadjustDropDownLabel.Text = 'Series to adjust';
            app.SeriestoadjustDropDown = uidropdown(app.GridLayout22);
            app.SeriestoadjustDropDown.Items = {'Series 1', 'Series 2', ''};
            app.SeriestoadjustDropDown.Value = 'Series 1';
            app.SmartAdjustTab = uitab(app.TabGroup2);
            app.SmartAdjustTab.Title = 'Smart Adjust';
            app.GridLayout21 = uigridlayout(app.SmartAdjustTab);
            app.AdjustTrackingSwitchLabel = uilabel(app.GridLayout21);
            app.AdjustTrackingSwitchLabel.Text = 'Adjust Tracking';
            app.AdjustTrackingSwitch = uiswitch(app.GridLayout21, 'slider');
            app.NextFramespacebarButton = uibutton(app.GridLayout21, 'push');
            app.NextFramespacebarButton.Text = 'Next Frame: spacebar';
            app.PreviousFramebkeyButton = uibutton(app.GridLayout21, 'push');
            app.PreviousFramebkeyButton.Text = 'Previous Frame: b key';
            app.zTHISPANELISNOTYETIMPLEMENTEDLabel = uilabel(app.GridLayout21);
            app.zTHISPANELISNOTYETIMPLEMENTEDLabel.Text = 'zTHIS PANEL IS NOT YET IMPLEMENTED';
            app.TriangulateTab = uitab(app.TabGroup);
            app.TriangulateTab.Title = 'Triangulate';
            app.TriangulateTab.ButtonDownFcn = createCallbackFcn(app, @TriangulateTabButtonDown, true);
            app.GridLayout25 = uigridlayout(app.TriangulateTab);
            app.ImagerGeometryPanel = uipanel(app.GridLayout25);
            app.ImagerGeometryPanel.Title = 'Imager Geometry';
            app.GridLayout27 = uigridlayout(app.ImagerGeometryPanel);
            app.GantryAngledegEditFieldLabel = uilabel(app.GridLayout27);
            app.GantryAngledegEditFieldLabel.Text = 'Gantry Angle (deg)';
            app.GantryAngledegEditField = uieditfield(app.GridLayout27, 'numeric');
            app.PixelSizemmEditField_2Label = uilabel(app.GridLayout27);
            app.PixelSizemmEditField_2Label.Text = 'Pixel Size (mm)';
            app.PixelSizemmEditField = uieditfield(app.GridLayout27, 'numeric');
            app.PixelSizemmEditField.Limits = [0 Inf];
            app.PixelSizemmEditField.Value = 0.388;
            app.SourcetoImageDistancemmEditFieldLabel = uilabel(app.GridLayout27);
            app.SourcetoImageDistancemmEditFieldLabel.Text = 'Source to Image Distance (mm)';
            app.SourcetoImageDistancemmEditField = uieditfield(app.GridLayout27, 'numeric');
            app.SourcetoImageDistancemmEditField.Limits = [0 Inf];
            app.SourcetoImageDistancemmEditField.Value = 1000;
            app.TriangulateButton = uibutton(app.GridLayout27, 'push');
            app.TriangulateButton.ButtonPushedFcn = createCallbackFcn(app, @TriangulateButtonPushed, true);
            app.TriangulateButton.Text = 'Triangulate';
            app.SeparateMotionsTab = uitab(app.TabGroup);
            app.SeparateMotionsTab.Title = 'Separate Motions';
            app.GridLayout29 = uigridlayout(app.SeparateMotionsTab);
            app.FilteringParametersPanel = uipanel(app.GridLayout29);
            app.FilteringParametersPanel.Title = 'Filtering Parameters';
            app.GridLayout30 = uigridlayout(app.FilteringParametersPanel);
            app.HeartRatebpmEditFieldLabel = uilabel(app.GridLayout30);
            app.HeartRatebpmEditFieldLabel.Text = 'Heart Rate (bpm)';
            app.HeartRateEditField = uieditfield(app.GridLayout30, 'numeric');
            app.HeartRateEditField.Limits = [0 Inf];
            app.HeartRateEditField.ValueChangedFcn = createCallbackFcn(app, @FcutFormulaValueChangedValueChanged, true);
            app.HeartRateEditField.Value = 60;
            app.RespiratoryPeriodsecbreathEditFieldLabel = uilabel(app.GridLayout30);
            app.RespiratoryPeriodsecbreathEditFieldLabel.Text = 'Respiratory Period (sec/breath)';
            app.RespiratoryPeriodsecbreathEditField = uieditfield(app.GridLayout30, 'numeric');
            app.RespiratoryPeriodsecbreathEditField.ValueChangedFcn = createCallbackFcn(app, @FcutFormulaValueChangedValueChanged, true);
            app.RespiratoryPeriodsecbreathEditField.Value = 3;
            app.FcutEditFieldLabel = uilabel(app.GridLayout30);
            app.FcutEditFieldLabel.Text = 'Fcut';
            app.FcutEditField = uieditfield(app.GridLayout30, 'numeric');
            app.FcutEditField.Limits = [0 Inf];
            app.FcutEditField.ValueChangedFcn = createCallbackFcn(app, @FcutEditFieldValueChanged, true);
            app.FcutEditField.Value = 0.67;
            app.LEditFieldLabel = uilabel(app.GridLayout30);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.GridLayout30, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @FcutFormulaValueChangedValueChanged, true);
            app.LEditField.Value = 0.5;
            app.FcutFrLFcFrLabel = uilabel(app.GridLayout30);
            app.FcutFrLFcFrLabel.Text = 'Fcut = Fr + L(Fc - Fr)';
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.GridLayout30);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.GridLayout30, 'numeric');
            app.SamplingFrequencyHzEditField.Limits = [0 Inf];
            app.SamplingFrequencyHzEditField.Value = 5;
            app.DetermineCutOffFrequencyPanel = uipanel(app.GridLayout29);
            app.DetermineCutOffFrequencyPanel.Title = 'Determine Cut-Off Frequency';
            app.GridLayout31 = uigridlayout(app.DetermineCutOffFrequencyPanel);
            app.UIAxes4 = uiaxes(app.GridLayout31);
            title(app.UIAxes4, 'Amplitude Spectrum')
            xlabel(app.UIAxes4, 'Frequency (Hz)')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout31);
            xlabel(app.UIAxes3, 'time (s)')
            ylabel(app.UIAxes3, 'Edge Pos (Pixels)')
            zlabel(app.UIAxes3, 'Z')
            app.MotionAngleEditFieldLabel = uilabel(app.GridLayout31);
            app.MotionAngleEditFieldLabel.Text = 'Motion Angle';
            app.MotionAngleEditField = uieditfield(app.GridLayout31, 'numeric');
            app.MotionAngleEditField.Editable = 'off';
            app.NewRespiratoryMotionSurrogateButton = uibutton(app.GridLayout31, 'push');
            app.NewRespiratoryMotionSurrogateButton.ButtonPushedFcn = createCallbackFcn(app, @NewRespiratoryMotionSurrogateButtonPushed, true);
            app.NewRespiratoryMotionSurrogateButton.Text = 'New Respiratory Motion Surrogate';
            app.ImageDropDownLabel = uilabel(app.GridLayout31);
            app.ImageDropDownLabel.Text = {'Image'; ''};
            app.ImageDropDown = uidropdown(app.GridLayout31);
            app.ImageDropDown.Items = {'Series 2', 'Series 1', ''};
            app.ImageDropDown.Value = 'Series 2';
            app.UsescurrentlyviewedimagesLabel = uilabel(app.GridLayout31);
            app.UsescurrentlyviewedimagesLabel.Text = {'Uses currently '; 'viewed images'};
            app.CoordinateforamplitudespectrumDropDownLabel = uilabel(app.GridLayout31);
            app.CoordinateforamplitudespectrumDropDownLabel.Text = 'Coordinate for amplitude spectrum';
            app.CoordinateforamplitudespectrumDropDown = uidropdown(app.GridLayout31);
            app.CoordinateforamplitudespectrumDropDown.Items = {'x', 'y', 'z'};
            app.CoordinateforamplitudespectrumDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinateforamplitudespectrumDropDownValueChanged, true);
            app.CoordinateforamplitudespectrumDropDown.Value = 'z';
            app.SeparateMotionsButton = uibutton(app.GridLayout29, 'push');
            app.SeparateMotionsButton.ButtonPushedFcn = createCallbackFcn(app, @SeparateMotionsButtonPushed, true);
            app.SeparateMotionsButton.Text = 'Separate Motions';
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.SaveTab.ButtonDownFcn = createCallbackFcn(app, @SaveTabButtonDown, true);
            app.GridLayout32 = uigridlayout(app.SaveTab);
            app.SaveButton = uibutton(app.GridLayout32, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Text = 'Save';
            app.TrackingDataCheckBox = uicheckbox(app.GridLayout32);
            app.TrackingDataCheckBox.Text = 'Tracking Data';
            app.TrackingDataCheckBox.Value = true;
            app.MotionSurrogateCheckBox = uicheckbox(app.GridLayout32);
            app.MotionSurrogateCheckBox.Text = 'Motion Surrogate';
            app.MotionSurrogateCheckBox.Value = true;
            app.SeparatedmotionsplotsCheckBox = uicheckbox(app.GridLayout32);
            app.SeparatedmotionsplotsCheckBox.Text = 'Separated motions plots';
            app.SeparatedmotionsplotsCheckBox.Value = true;
            app.FileIdentifierFormatDate_Identifier_XXXXLabel = uilabel(app.GridLayout32);
            app.FileIdentifierFormatDate_Identifier_XXXXLabel.Text = {'File Identifier '; ''};
            app.FileIdentifierEditField = uieditfield(app.GridLayout32, 'text');
            app.FileFormatDate_Identifier_XXXXXLabel = uilabel(app.GridLayout32);
            app.FileFormatDate_Identifier_XXXXXLabel.Text = 'File Format: Date_Identifier_XXXXX';
            app.TrackingMovieCheckBox = uicheckbox(app.GridLayout32);
            app.TrackingMovieCheckBox.Text = 'Tracking Movie';
            app.TrackingMovieCheckBox.Value = true;
            app.Tree2 = uitree(app.GridLayout32, 'checkbox');
            app.ImageDataSeries2SaveNode = uitreenode(app.Tree2);
            app.ImageDataSeries2SaveNode.Text = 'Series 2';
            % Assign Checked Nodes
            app.Tree2.CheckedNodes = [app.ImageDataSeries2SaveNode];
            app.Tree = uitree(app.GridLayout32, 'checkbox');
            app.ImageDataSeries1SaveNode = uitreenode(app.Tree);
            app.ImageDataSeries1SaveNode.Text = 'Series 1';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.ImageDataSeries1SaveNode];
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Second Image Series')
            xlabel(app.UIAxes2, 'Sup-Inf')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'First Image Series')
            xlabel(app.UIAxes, 'Sup-Inf')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ProjectionSlider = uislider(app.GridLayout3);
            app.ProjectionSlider.Limits = [1 5];
            app.ProjectionSlider.ValueChangedFcn = createCallbackFcn(app, @ProjectionSliderValueChanged, true);
            app.ProjectionSlider.ValueChangingFcn = createCallbackFcn(app, @ProjectionSliderValueChanging, true);
            app.ProjectionSlider.Value = 1;
            app.GridLayout23 = uigridlayout(app.GridLayout3);
            app.ImagetoViewDropDown = uidropdown(app.GridLayout23);
            app.ImagetoViewDropDown.Items = {'Unfiltered'};
            app.ImagetoViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectionSliderValueChanged, true);
            app.ImagetoViewDropDown.Value = 'Unfiltered';
            app.ImagetoViewDropDownLabel = uilabel(app.GridLayout23);
            app.ImagetoViewDropDownLabel.Text = 'Image to View';
            app.GridLayout24 = uigridlayout(app.GridLayout3);
            app.ShowTrackedLeadsCheckBox = uicheckbox(app.GridLayout24);
            app.ShowTrackedLeadsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProjectionSliderValueChanged, true);
            app.ShowTrackedLeadsCheckBox.Text = 'Show Tracked Leads';
            app.PlayFramesButton = uibutton(app.GridLayout24, 'push');
            app.PlayFramesButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFramesButtonPushed, true);
            app.PlayFramesButton.Text = 'Play Frames';
            app.FrameDelaysEditFieldLabel = uilabel(app.GridLayout24);
            app.FrameDelaysEditFieldLabel.Text = 'Frame Delay (s)';
            app.FrameDelaysEditField = uieditfield(app.GridLayout24, 'numeric');
            app.FrameDelaysEditField.Limits = [0 Inf];
            app.FrameDelaysEditField.Value = 0.2;
            app.GridLayout28 = uigridlayout(app.GridLayout3);
            app.PreviousFrameButton = uibutton(app.GridLayout28, 'push');
            app.PreviousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFrameButtonPushed, true);
            app.PreviousFrameButton.Text = 'Previous';
            app.NextFrameButton = uibutton(app.GridLayout28, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next';
            app.FrameLabel = uilabel(app.GridLayout28);
            app.FrameLabel.Text = 'Frame';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackingAppV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end